classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Image2                    matlab.ui.control.Image
        VibrationCalculatorLabel  matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        SingleDoFTab              matlab.ui.container.Tab
        Image3                    matlab.ui.control.Image
        HzEditField               matlab.ui.control.NumericEditField
        HzEditFieldLabel          matlab.ui.control.Label
        msEditField               matlab.ui.control.NumericEditField
        msEditFieldLabel          matlab.ui.control.Label
        mEditField                matlab.ui.control.NumericEditField
        mEditFieldLabel           matlab.ui.control.Label
        FtAsintLabel              matlab.ui.control.Label
        sEditField                matlab.ui.control.NumericEditField
        sEditFieldLabel           matlab.ui.control.Label
        radsEditField_6           matlab.ui.control.NumericEditField
        radsEditField_6Label      matlab.ui.control.Label
        radsEditField_5           matlab.ui.control.NumericEditField
        radsEditField_5Label      matlab.ui.control.Label
        radsEditField_4           matlab.ui.control.NumericEditField
        radsEditField_4Label      matlab.ui.control.Label
        radsEditField_3           matlab.ui.control.NumericEditField
        radsEditField_3Label      matlab.ui.control.Label
        radsEditField_2           matlab.ui.control.NumericEditField
        radsEditField_2Label      matlab.ui.control.Label
        Label_5                   matlab.ui.control.Label
        CalculateButton           matlab.ui.control.Button
        radsEditField             matlab.ui.control.NumericEditField
        radsLabel                 matlab.ui.control.Label
        ANEditField               matlab.ui.control.NumericEditField
        ANLabel                   matlab.ui.control.Label
        Label_3                   matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        NsmEditField              matlab.ui.control.NumericEditField
        NsmEditFieldLabel         matlab.ui.control.Label
        NmEditField               matlab.ui.control.NumericEditField
        NmEditFieldLabel          matlab.ui.control.Label
        kgEditField               matlab.ui.control.NumericEditField
        kgEditFieldLabel          matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        UIAxes_3                  matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
        MultiDoFTab               matlab.ui.container.Tab
        CalculateButton_6         matlab.ui.control.Button
        HzEditField_2             matlab.ui.control.NumericEditField
        HzEditField_2Label        matlab.ui.control.Label
        sEditField_2              matlab.ui.control.NumericEditField
        sEditField_2Label         matlab.ui.control.Label
        DropDown_6                matlab.ui.control.DropDown
        DropDown_6Label_2         matlab.ui.control.Label
        Spinner_6                 matlab.ui.control.Spinner
        Label_31                  matlab.ui.control.Label
        Image18_4                 matlab.ui.control.Image
        Image18_3                 matlab.ui.control.Image
        Image18_2                 matlab.ui.control.Image
        Image18                   matlab.ui.control.Image
        Image17                   matlab.ui.control.Image
        Image16                   matlab.ui.control.Image
        Image15                   matlab.ui.control.Image
        Image14                   matlab.ui.control.Image
        Spinner_5                 matlab.ui.control.Spinner
        Label_30                  matlab.ui.control.Label
        Spinner_4                 matlab.ui.control.Spinner
        Label_29                  matlab.ui.control.Label
        Label_28                  matlab.ui.control.Label
        radsEditField_14          matlab.ui.control.NumericEditField
        radsLabel_5               matlab.ui.control.Label
        radsEditField_13          matlab.ui.control.NumericEditField
        radsLabel_2               matlab.ui.control.Label
        radsEditField_12          matlab.ui.control.NumericEditField
        radsLabel_3               matlab.ui.control.Label
        radsEditField_11          matlab.ui.control.NumericEditField
        radsLabel_4               matlab.ui.control.Label
        Label_27                  matlab.ui.control.Label
        radsEditField_10          matlab.ui.control.NumericEditField
        radsEditField_10Label     matlab.ui.control.Label
        v5msEditField             matlab.ui.control.NumericEditField
        v5msEditFieldLabel        matlab.ui.control.Label
        v4msEditField             matlab.ui.control.NumericEditField
        v4msEditFieldLabel        matlab.ui.control.Label
        v3msEditField             matlab.ui.control.NumericEditField
        v3msEditFieldLabel        matlab.ui.control.Label
        v2msEditField             matlab.ui.control.NumericEditField
        v2msEditFieldLabel        matlab.ui.control.Label
        v1msEditField             matlab.ui.control.NumericEditField
        v1msEditFieldLabel        matlab.ui.control.Label
        x5mEditField              matlab.ui.control.NumericEditField
        x5mEditFieldLabel         matlab.ui.control.Label
        x4mEditField              matlab.ui.control.NumericEditField
        x4mEditFieldLabel         matlab.ui.control.Label
        x3mEditField              matlab.ui.control.NumericEditField
        x3mEditFieldLabel         matlab.ui.control.Label
        x2mEditField              matlab.ui.control.NumericEditField
        x2mEditFieldLabel         matlab.ui.control.Label
        x1mEditField              matlab.ui.control.NumericEditField
        x1mEditFieldLabel         matlab.ui.control.Label
        k4NmEditField             matlab.ui.control.NumericEditField
        k4NmEditFieldLabel        matlab.ui.control.Label
        k3NmEditField_2           matlab.ui.control.NumericEditField
        k3NmEditField_2Label      matlab.ui.control.Label
        k3NmEditField             matlab.ui.control.NumericEditField
        k3NmEditFieldLabel        matlab.ui.control.Label
        k2NmEditField             matlab.ui.control.NumericEditField
        k2NmEditFieldLabel        matlab.ui.control.Label
        k1NmEditField             matlab.ui.control.NumericEditField
        k1NmEditFieldLabel        matlab.ui.control.Label
        m5kgEditField             matlab.ui.control.NumericEditField
        m5kgEditFieldLabel        matlab.ui.control.Label
        m4kgEditField             matlab.ui.control.NumericEditField
        m4kgEditFieldLabel        matlab.ui.control.Label
        m3kgEditField             matlab.ui.control.NumericEditField
        m3kgEditFieldLabel        matlab.ui.control.Label
        m2kgEditField             matlab.ui.control.NumericEditField
        m2kgEditFieldLabel        matlab.ui.control.Label
        m1kgEditField             matlab.ui.control.NumericEditField
        m1kgEditFieldLabel        matlab.ui.control.Label
        Label_26                  matlab.ui.control.Label
        Label_25                  matlab.ui.control.Label
        Image13                   matlab.ui.control.Image
        CalculateButton_5         matlab.ui.control.Button
        UIAxes7                   matlab.ui.control.UIAxes
        UIAxes6                   matlab.ui.control.UIAxes
        ContinuumBodyTab          matlab.ui.container.Tab
        TabGroup2                 matlab.ui.container.TabGroup
        RodTab                    matlab.ui.container.Tab
        Image11                   matlab.ui.control.Image
        NmLabel_2                 matlab.ui.control.Label
        kgLabel_2                 matlab.ui.control.Label
        kgLabel                   matlab.ui.control.Label
        NmLabel                   matlab.ui.control.Label
        EditField_4               matlab.ui.control.NumericEditField
        Spinner                   matlab.ui.control.Spinner
        Label_14                  matlab.ui.control.Label
        Image4_2                  matlab.ui.control.Image
        EditField5                matlab.ui.control.NumericEditField
        radsEditField_7           matlab.ui.control.NumericEditField
        radsEditField_7Label      matlab.ui.control.Label
        Label_8                   matlab.ui.control.Label
        CalculateButton_2         matlab.ui.control.Button
        DropDown_2                matlab.ui.control.DropDown
        Label_12                  matlab.ui.control.Label
        DropDown                  matlab.ui.control.DropDown
        Label_11                  matlab.ui.control.Label
        Image4                    matlab.ui.control.Image
        Label_10                  matlab.ui.control.Label
        kgm3EditField             matlab.ui.control.NumericEditField
        kgm3EditFieldLabel        matlab.ui.control.Label
        Am2EditField              matlab.ui.control.NumericEditField
        Am2EditFieldLabel         matlab.ui.control.Label
        EGPaEditField             matlab.ui.control.NumericEditField
        EGPaEditFieldLabel        matlab.ui.control.Label
        LmEditField               matlab.ui.control.NumericEditField
        LmEditFieldLabel          matlab.ui.control.Label
        Label_7                   matlab.ui.control.Label
        Label_9                   matlab.ui.control.Label
        Label_6                   matlab.ui.control.Label
        UIAxes2                   matlab.ui.control.UIAxes
        ShaftTab                  matlab.ui.container.Tab
        NmradLabel                matlab.ui.control.Label
        kgm2Label                 matlab.ui.control.Label
        kgm2Label_2               matlab.ui.control.Label
        NmradLabel_2              matlab.ui.control.Label
        DropDown_4                matlab.ui.control.DropDown
        DropDown_4Label           matlab.ui.control.Label
        Spinner_2                 matlab.ui.control.Spinner
        Spinner_2Label            matlab.ui.control.Label
        radsEditField_8           matlab.ui.control.NumericEditField
        radsEditField_8Label      matlab.ui.control.Label
        DropDown_3                matlab.ui.control.DropDown
        DropDown_3Label           matlab.ui.control.Label
        kgm3EditField_2           matlab.ui.control.NumericEditField
        kgm3EditField_2Label      matlab.ui.control.Label
        rmEditField               matlab.ui.control.NumericEditField
        rmEditFieldLabel          matlab.ui.control.Label
        GGPaEditField             matlab.ui.control.NumericEditField
        GGPaEditFieldLabel        matlab.ui.control.Label
        LmEditField_2             matlab.ui.control.NumericEditField
        LmLabel                   matlab.ui.control.Label
        EditField_5               matlab.ui.control.NumericEditField
        Image4_6                  matlab.ui.control.Image
        Image4_5                  matlab.ui.control.Image
        EditField5_2              matlab.ui.control.NumericEditField
        Label_19                  matlab.ui.control.Label
        CalculateButton_3         matlab.ui.control.Button
        Image4_4                  matlab.ui.control.Image
        Label_18                  matlab.ui.control.Label
        Label_17                  matlab.ui.control.Label
        Label_16                  matlab.ui.control.Label
        Label_15                  matlab.ui.control.Label
        UIAxes2_2                 matlab.ui.control.UIAxes
        BeamTab                   matlab.ui.container.Tab
        NmradLabel_4              matlab.ui.control.Label
        NmradLabel_3              matlab.ui.control.Label
        EditField_9               matlab.ui.control.NumericEditField
        EditField_8               matlab.ui.control.NumericEditField
        Image20                   matlab.ui.control.Image
        Image19                   matlab.ui.control.Image
        kgLabel_4                 matlab.ui.control.Label
        NmLabel_4                 matlab.ui.control.Label
        EditField_7               matlab.ui.control.NumericEditField
        kgLabel_3                 matlab.ui.control.Label
        NmLabel_3                 matlab.ui.control.Label
        EditField_6               matlab.ui.control.NumericEditField
        Am2EditField_2            matlab.ui.control.NumericEditField
        Am2EditField_2Label       matlab.ui.control.Label
        CODE_NAME                 matlab.ui.control.DropDown
        DropDown_6Label           matlab.ui.control.Label
        Spinner_3                 matlab.ui.control.Spinner
        Spinner_3Label            matlab.ui.control.Label
        radsEditField_9           matlab.ui.control.NumericEditField
        radsEditField_9Label      matlab.ui.control.Label
        DropDown_5                matlab.ui.control.DropDown
        DropDown_5Label           matlab.ui.control.Label
        kgm3EditField_3           matlab.ui.control.NumericEditField
        kgm3EditField_3Label      matlab.ui.control.Label
        Im4EditField              matlab.ui.control.NumericEditField
        Im4EditFieldLabel         matlab.ui.control.Label
        EGPaEditField_2           matlab.ui.control.NumericEditField
        EGPaEditField_2Label      matlab.ui.control.Label
        LmEditField_3             matlab.ui.control.NumericEditField
        LmLabel_2                 matlab.ui.control.Label
        Label_24                  matlab.ui.control.Label
        CalculateButton_4         matlab.ui.control.Button
        Label_23                  matlab.ui.control.Label
        Label_22                  matlab.ui.control.Label
        Label_21                  matlab.ui.control.Label
        Label_20                  matlab.ui.control.Label
        UIAxes2_3                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %% DataRead
            %系统参数
            m=app.kgEditField.Value; %单自由度振动块质量，单位：kg
            k=app.NmEditField.Value; %弹簧刚度，单位：N/m
            c=app.NsmEditField.Value; %阻尼系数，单位：N*s/m
            %初始条件
            x0=app.mEditField.Value; %初始位移，单位：m
            v0=app.msEditField.Value; %初始速度，单位：m/s
            %外部激励
            %形式：F(t)=a_e*sin(omega_e*t)
            a_e=app.ANEditField.Value; %激励幅值，单位：m
            omega_e=app.radsEditField.Value; %激励角速度，单位：rad/s
            %仿真设置
            t_end=app.sEditField.Value; %计算时间，单位：s（取整数）
            t_freq=app.HzEditField.Value; %步长设置，输入1秒内的采样点数
            %% Calculate
            %系统振动各圆频率计算
            %自由振动下各共振圆频率
            omega_n=sqrt(k/m); %无阻尼自由振动固有圆频率
            zeta=c/(2*omega_n*m); %系统阻尼比
            omega_d=sqrt(1-zeta^2)*omega_n; %阻尼振动固有圆频率
            %受迫振动下各共振圆频率
            omega_x=omega_n*sqrt(1-2*zeta^2); %位移共振圆频率
            omega_v=omega_n; %速度共振圆频率
            omega_a=omega_n/sqrt(1-2*zeta^2); %加速度共振圆频率
                                 
            %% Output 
            app.radsEditField_2.Value=omega_n;
            app.radsEditField_3.Value=omega_d;
            app.radsEditField_4.Value=omega_x;
            app.radsEditField_5.Value=omega_v;
            app.radsEditField_6.Value=omega_a;

            %振动曲线绘制：位置、速度、加速度
            X=a_e/(m*sqrt((omega_n^2-omega_e^2)^2+(2*zeta*omega_n*omega_e)^2));
            phi=(c*omega_e)/(k-m*omega_e^2);
            A1=((v0+zeta*omega_n*x0)/omega_d)+X*((zeta*omega_n*sin(phi)-omega_e*cos(phi))/omega_d);
            A2=x0+X*sin(phi);
            
            t = linspace(0,t_end,t_end*t_freq+1); % 时间轴坐标
            x = exp(-1.*zeta.*omega_n.*t).*(A1.*sin(omega_d*t)+A2.*cos(omega_d*t))+X.*sin(omega_e*t-phi); % 根据时间轴正弦信号
            v = diff(exp(-1.*zeta.*omega_n.*t).*(A1.*sin(omega_d*t)+A2.*cos(omega_d*t))+X.*sin(omega_e*t-phi),1)*t_freq;
            v(t_end*t_freq+1)=v(t_end*t_freq);
            a = diff(exp(-1.*zeta.*omega_n.*t).*(A1.*sin(omega_d*t)+A2.*cos(omega_d*t))+X.*sin(omega_e*t-phi),2)*t_freq;
            a(t_end*t_freq)=a(t_end*t_freq-1);
            a(t_end*t_freq+1)=a(t_end*t_freq);
            plot(app.UIAxes,t,x,'linewidth',1.5); % 括号里第一项是要显示在那个坐标区的名称，后面分别是横坐标和纵坐标
            plot(app.UIAxes_2,t,v,'linewidth',1.5);
            plot(app.UIAxes_3,t,a,'linewidth',1.5);
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            switch value
                case "固定端"
                    app.Image4.ImageSource='杆-固定端-左.jpg';
                    app.kgLabel_2.Visible='off';
                    app.NmLabel_2.Visible='off';
                    app.EditField5.Visible='off';
                case "自由端"
                    app.Image4.ImageSource='杆-自由端-左.jpg';
                    app.kgLabel_2.Visible='off';
                    app.NmLabel_2.Visible='off';
                    app.EditField5.Visible='off';
                case "弹性约束"
                    app.Image4.ImageSource='杆-弹性约束-左.jpg';
                    app.kgLabel_2.Visible='off';
                    app.NmLabel_2.Visible='on';
                    app.EditField5.Visible='on';
                case "惯性约束"
                    app.Image4.ImageSource='杆-惯性约束-左.jpg';
                    app.kgLabel_2.Visible='on';
                    app.NmLabel_2.Visible='off';
                    app.EditField5.Visible='on';
            end
        end

        % Value changed function: CODE_NAME, Spinner
        function SpinnerValueChanged(app, event)
            value=app.CODE_NAME.Value;
            switch value
                case '固定端'
                    app.EditField_6.Visible='off';
                    app.EditField_9.Visible='off';
                    app.kgLabel_3.Visible='off';
                    app.NmLabel_3.Visible='off';
                    app.Image20.ImageSource='梁-固定端-右.jpg';
                    app.NmradLabel_4.Visible='off';
                case '自由端'
                    app.EditField_6.Visible='off';
                    app.EditField_9.Visible='off';
                    app.kgLabel_3.Visible='off';
                    app.NmLabel_3.Visible='off';
                    app.Image20.ImageSource='梁-自由端-右.jpg';
                    app.NmradLabel_4.Visible='off';
                case '铰支端'
                    app.EditField_6.Visible='off';
                    app.EditField_9.Visible='off';
                    app.kgLabel_3.Visible='off';
                    app.NmLabel_3.Visible='off';
                    app.Image20.ImageSource='梁-铰支端-右.jpg';
                    app.NmradLabel_4.Visible='off';
                case '弹性约束'
                    app.EditField_6.Visible='on';
                    app.EditField_9.Visible='on';
                    app.kgLabel_3.Visible='off';
                    app.NmLabel_3.Visible='on';
                    app.Image20.ImageSource='梁-弹性约束-右.jpg';
                    app.NmradLabel_4.Visible='on';
                case '惯性约束'
                    app.EditField_6.Visible='on';
                    app.EditField_9.Visible='off';
                    app.kgLabel_3.Visible='on';
                    app.NmLabel_3.Visible='off';
                    app.Image20.ImageSource='梁-惯性约束-右.jpg';
                    app.NmradLabel_4.Visible='off';
            end
        end

        % Value changing function: Spinner
        function SpinnerValueChanging(app, event)
           
        end

        % Callback function
        function EditField6ValueChanged(app, event)
            
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            switch value
                case "固定端"
                    app.Image4_2.ImageSource='杆-固定端-右.jpg';
                    app.NmLabel.Visible='off';
                    app.kgLabel.Visible='off';
                    app.EditField_4.Visible='off';
                case "自由端"
                    app.Image4_2.ImageSource='杆-自由端-右.jpg';
                    app.kgLabel.Visible='off';
                    app.NmLabel.Visible='off';
                    app.EditField_4.Visible='off';
                case "弹性约束"
                    app.Image4_2.ImageSource='杆-弹性约束-右.jpg';
                    app.kgLabel.Visible='off';
                    app.NmLabel.Visible='on';
                    app.EditField_4.Visible='on';
                case "惯性约束"
                    app.Image4_2.ImageSource='杆-惯性约束-右.jpg';
                    app.kgLabel.Visible='on';
                    app.NmLabel.Visible='off';
                    app.EditField_4.Visible='on';
            end
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            %% 参数读入
            l=app.LmEditField.Value; %杆长，单位：m
            a=app.Am2EditField.Value; %横截面积，单位：
            E=app.EGPaEditField.Value*1e9; %弹性模量，转换为单位：Pa
            rho=app.kgm3EditField.Value; %密度，单位：kg/m^3
            n=app.Spinner.Value;
            %%计算
            c=sqrt(E/rho);
            % 根据边界条件求解固有振型函数的参数a1,a2,omega
            % U(x)=a1*cos(omega*x/c)+a2*sin(omega*x/c)
            %读入边界条件
            switch app.DropDown.Value
                case "固定端"
                    a1=0;
                    a2=1;
                    switch app.DropDown_2.Value
                        case "固定端" %1：两端固定
                            omega=c*n*pi/l;
                        case "自由端" %2:左端固定，右端自由
                            omega=c*(n-0.5)*pi/l;
                        case "弹性约束"
                            k=app.EditField_4.Value;
                            omega=fzero(@(x) k*sin(x*l/c)+E*a*x/c*cos(x*l/c),[(n-0.5)*pi*c/l,(n+0.5)*pi*c/l]);
                        case "惯性约束"
                            m=app.EditField_4.Value;
                            omega=fzero(@(x) sin(x*l/c)-cos(x*l/c)*E*a/(m*c*x),[(n-1+1e-20)*pi*c/l,(n-0.5)*pi*c/l]);
                    end
                    %% output
                    app.radsEditField_7.Value=omega;
                    rod_x = linspace(0,l,300); % 主振型横坐标离散
                    rod_y = a1.*cos(omega.*rod_x./c)+a2.*sin(omega.*rod_x./c);
                    plot(app.UIAxes2,rod_x,rod_y);
                case "自由端"
                    a1=1;
                    a2=0;
                    switch app.DropDown_2.Value
                        case "固定端"
                            omega=c*(n-0.5)*pi/l;
                        case "自由端"
                            omega=c*(n-1)*pi/l;
                        case "弹性约束"
                            k=app.EditField_4.Value;
                            if n==1
                                omega=fzero(@(x) k*cos(x*l/c)-E*a*x/c*sin(x*l/c),[0*pi*c/l,0.5*pi*c/l]);
                            else
                                omega=fzero(@(x) k*cos(x*l/c)-E*a*x/c*sin(x*l/c),[(n-1.5)*pi*c/l,(n-0.5)*pi*c/l]);
                            end
                        case "惯性约束"
                            m=app.EditField_4.Value;
                            if n==1
                                omega=fzero(@(x) c*m*x*cos(x*l/c)+sin(x*l/c)*E*a,[0*pi*c/l,0.5*pi*c/l]);
                            else
                                omega=fzero(@(x) c*m*x*cos(x*l/c)+sin(x*l/c)*E*a,[(n-1.5)*pi*c/l,(n-0.5)*pi*c/l]);
                            end
                    end
                    %% output
                    app.radsEditField_7.Value=omega;
                    rod_x = linspace(0,l,300); % 主振型横坐标离散
                    rod_y = a1.*cos(omega.*rod_x./c)+a2.*sin(omega.*rod_x./c);
                    plot(app.UIAxes2,rod_x,rod_y);
                case "弹性约束"
                    switch app.DropDown_2.Value
                        case "固定端"
                            k=app.EditField5.Value;
                            omega=fzero(@(x) sin(l*x/c)+cos(l*x/c)*E*a/(k*c)*x,[(n-0.5)*pi*c/l,(n)*pi*c/l]);
                            a2=sqrt(1/(1+(E*a*omega/(k*c))^2));
                            a1=sqrt(1-a2^2);
                        case "自由端"
                            k=app.EditField5.Value;
                            omega=fzero(@(x) sin(l*x/c)-cos(l*x/c)*k*c/(E*a*x),[(n-1+1e-20)*pi*c/l,(n-0.5)*pi*c/l]);
                            a2=sqrt(1/(1+(E*a*omega/(k*c))^2));
                            a1=sqrt(1-a2^2);
                        case "弹性约束"
                            k1=app.EditField5.Value;
                            k2=app.EditField_4.Value;
                            if n==1
                                omega=fzero(@(x) E*a*(k1+k2)*x/(c*k1)*cos(x*l/c)+(k2-(E*a*x/c)^2/k1)*sin(x*l/c),[(n-1+1e-10)*pi*c/l,(n-0.5)*pi*c/l]);
                            else
                                omega=fzero(@(x) E*a*(k1+k2)*x/(c*k1)*cos(x*l/c)+(k2-(E*a*x/c)^2/k1)*sin(x*l/c),[(n-1)*pi*c/l,(n)*pi*c/l]);
                            end
                            a2=sqrt(1/(1+(E*a*omega/(k1*c))^2));
                            a1=sqrt(1-a2^2);
                        case "惯性约束"
                            k=app.EditField5.Value;
                            m=app.EditField_4.Value;
                            if n==1
                            omega=fzero(@(x) (m*x^2*E*a/k-E*a)*cos(x*l/c)+(E^2*a^2*x/(c*k))*sin(x*l/c),[(1e-20)*pi*c/l,(0.5)*pi*c/l]);
                            else
                            omega=fzero(@(x) (m*x^2*E*a/k-E*a)*cos(x*l/c)+(m*x*c+E^2*a^2*x/(c*k))*sin(x*l/c),[(n-1.5)*pi*c/l,(n-0.5)*pi*c/l]);
                            end
                            a2=sqrt(1/(1+(E*a*omega/(k*c))^2));
                            a1=sqrt(1-a2^2);
                    end
                    %% output
                    app.radsEditField_7.Value=omega;
                    rod_x = linspace(0,l,300); % 主振型横坐标离散
                    rod_y = a1.*cos(omega.*rod_x./c)+a2.*sin(omega.*rod_x./c);
                    plot(app.UIAxes2,rod_x,rod_y);
                case "惯性约束"
                    m=app.EditField5.Value;
                    switch app.DropDown_2.Value
                        case "固定端"
                        omega=fzero(@(x) sin(x*l/c)-cos(x*l/c)*E*a/(m*c*x),[(n-1+1e-20)*pi*c/l,(n-0.5)*pi*c/l]);
                        a1=0;
                        a2=1;
                        %% output
                        app.radsEditField_7.Value=omega;
                        rod_x = linspace(0,l,300); % 主振型横坐标离散
                        rod_y = a1.*cos(omega.*rod_x./c)+a2.*sin(omega.*rod_x./c);
                        rod_y1 = flip(rod_y);
                        plot(app.UIAxes2,rod_x,rod_y1);
                        case "自由端"
                        a1=1;
                        a2=0;
                        if n ==1
                            omega=fzero(@(x) c*m*x*cos(x*l/c)+sin(x*l/c)*E*a,[0*pi*c/l,0.5*pi*c/l]);
                        else
                            omega=fzero(@(x) c*m*x*cos(x*l/c)+sin(x*l/c)*E*a,[(n-1.5)*pi*c/l,(n-0.5)*pi*c/l]);
                        end
                        %% output
                        app.radsEditField_7.Value=omega;
                        rod_x = linspace(0,l,300); % 主振型横坐标离散
                        rod_y = a1.*cos(omega.*rod_x./c)+a2.*sin(omega.*rod_x./c);
                        rod_y1 = flip(rod_y);
                        plot(app.UIAxes2,rod_x,rod_y1);
                        case "弹性约束"
                        k=app.EditField_4.Value;
                        if n==1
                            omega=fzero(@(x) (m*x^2*E*a/k-E*a)*cos(x*l/c)+(E^2*a^2*x/(c*k))*sin(x*l/c),[0*pi*c/l,(0.5)*pi*c/l]);
                        else
                            omega=fzero(@(x) (m*x^2*E*a/k-E*a)*cos(x*l/c)+(m*x*c+E^2*a^2*x/(c*k))*sin(x*l/c),[(n-1.5)*pi*c/l,(n-0.5)*pi*c/l]);
                        end
                        a2=sqrt(1/(1+(E*a*omega/(k*c))^2));
                        a1=sqrt(1-a2^2);
                        %% output
                        app.radsEditField_7.Value=omega;
                        rod_x = linspace(0,l,300); % 主振型横坐标离散
                        rod_y = a1.*cos(omega.*rod_x./c)+a2.*sin(omega.*rod_x./c);
                        rod_y1 = flip(rod_y);
                        plot(app.UIAxes2,rod_x,rod_y1);
                        case "惯性约束"
                        m1=app.EditField5.Value;
                        m2=app.EditField_4.Value;
                        if n==1
                            omega=0;
                        else
                            omega=fzero(@(x) (-1*E*a*((m1+m2)/m1))*cos(x*l/c)+(m2*x*c-(E*a)^2/(m1*x*c))*sin(x*l/c),[(n-2+1e-20)*pi*c/l,(n-1)*pi*c/l]);  
                        end
                        a2=-sqrt(1/(1+(E*a/(m1*omega*c))^2));
                        a1=1*sqrt(1-a2^2);
                        %% output
                        app.radsEditField_7.Value=omega;
                        rod_x = linspace(0,l,300); % 主振型横坐标离散
                        rod_y = a1.*cos(omega.*rod_x./c)+a2.*sin(omega.*rod_x./c);
                        plot(app.UIAxes2,rod_x,rod_y);
                    end
            end
%             % output
%             app.radsEditField_7.Value=omega;
%             rod_x = linspace(0,l,300); % 主振型横坐标离散
%             rod_y = a1.*cos(omega.*rod_x./c)+a2.*sin(omega.*rod_x./c);
%             plot(app.UIAxes2,rod_x,rod_y);
        end

        % Button pushed function: CalculateButton_3
        function CalculateButton_3Pushed(app, event)
            %% 参数读入
            l=app.LmEditField_2.Value; %杆长，单位：m
%             A=app.rmEditField.Value^2*pi; %横截面积，单位：m^2
            R=app.rmEditField.Value;
            J=pi*R^4/2;
            G=app.GGPaEditField.Value*1e9; %剪切模量，转换为单位：Pa
            rho=app.kgm3EditField_2.Value; %密度，单位：kg/m^3
            n=app.Spinner_2.Value;
            %%计算
            a=sqrt(G/rho);
            % 根据边界条件求解固有振型函数的参数a1,a2,omega
            % U(x)=a1*cos(omega*x/c)+a2*sin(omega*x/c)
            %读入边界条件
            switch app.DropDown_3.Value
                case "固定端"
                    a1=0;
                    a2=1;
                    switch app.DropDown_4.Value
                        case "固定端" %1：两端固定                            
                            omega=a*n*pi/l;
                        case "自由端" %2:左端固定，右端自由
                            omega=a*(n-0.5)*pi/l;
                        case "弹性约束"
                            k=app.EditField_5.Value;
                            omega=fzero(@(x) k*sin(x*l/a)+G*J*x/a*cos(x*l/a),[(n-0.5)*pi*a/l,(n+0.5)*pi*a/l]);
                        case "惯性约束"
                            I=app.EditField_5.Value;
                            omega=fzero(@(x) sin(x*l/a)-cos(x*l/a)*G*J/(I*a*x),[(n-1+1e-20)*pi*a/l,(n-0.5)*pi*a/l]);  
                    end
                %% output
                app.radsEditField_8.Value=omega;
                rod_x = linspace(0,l,300); % 主振型横坐标离散
                rod_y = a1.*cos(omega.*rod_x./a)+a2.*sin(omega.*rod_x./a);
                plot(app.UIAxes2_2,rod_x,rod_y);
                case "自由端"
                    a1=1;
                    a2=0;
                    switch app.DropDown_4.Value
                        case "固定端"
                            omega=a*(n-0.5)*pi/l;
                        case "自由端"
                            omega=a*(n-1)*pi/l;
                        case "弹性约束"
                            k=app.EditField_5.Value;
                            if n==1
                                omega=fzero(@(x) k*cos(x*l/a)-G*J*x/a*sin(x*l/a),[0*pi*a/l,0.5*pi*a/l]);
                            else
                                omega=fzero(@(x) k*cos(x*l/a)-G*J*x/a*sin(x*l/a),[(n-1.5)*pi*a/l,(n-0.5)*pi*a/l]);
                            end
                        case "惯性约束"
                            I=app.EditField_5.Value;
                            if n==1
                                omega=fzero(@(x) a*I*x*cos(x*l/a)+sin(x*l/a)*G*J,[0*pi*a/l,0.5*pi*a/l]);
                            else
                                omega=fzero(@(x) a*I*x*cos(x*l/a)+sin(x*l/a)*G*J,[(n-1.5)*pi*a/l,(n-0.5)*pi*a/l]);
                            end
                    end
                %% output
                app.radsEditField_8.Value=omega;
                rod_x = linspace(0,l,300); % 主振型横坐标离散
                rod_y = a1.*cos(omega.*rod_x./a)+a2.*sin(omega.*rod_x./a);
                plot(app.UIAxes2_2,rod_x,rod_y);
                case "弹性约束" 
                    switch app.DropDown_4.Value
                        case "固定端"
                            k=app.EditField5_2.Value;
                            omega=fzero(@(x) sin(l*x/a)+cos(l*x/a)*G*J/(k*a)*x,[(n-0.5)*pi*a/l,(n)*pi*a/l]);
                            a2=sqrt(1/(1+(G*J*omega/(k*a))^2));
                            a1=sqrt(1-a2^2);
                        case "自由端"
                            k=app.EditField5_2.Value;
                            omega=fzero(@(x) sin(l*x/a)-cos(l*x/a)*k*a/(G*J*x),[(n-1+1e-20)*pi*a/l,(n-0.5)*pi*a/l]);
                            a2=sqrt(1/(1+(G*J*omega/(k*a))^2));
                            a1=sqrt(1-a2^2);
                        case "弹性约束"
                            k1=app.EditField5_2.Value;
                            k2=app.EditField_5.Value;
                            if n==1
                                omega=fzero(@(x) G*J*(k1+k2)*x/(a*k1)*cos(x*l/a)+(k2-(G*J*x/a)^2/k1)*sin(x*l/a),[(n-1+1e-10)*pi*a/l,(n-0.5)*pi*a/l]);
                            else
                                omega=fzero(@(x) G*J*(k1+k2)*x/(a*k1)*cos(x*l/a)+(k2-(G*J*x/a)^2/k1)*sin(x*l/a),[(n-1)*pi*a/l,(n)*pi*a/l]);
                            end
                            a2=sqrt(1/(1+(G*J*omega/(k1*a))^2));
                            a1=sqrt(1-a2^2);
                        case "惯性约束"
                            k=app.EditField5_2.Value;
                            I=app.EditField_5.Value;
                            if n==1
                            omega=fzero(@(x) (I*x^2*G*J/k-G*J)*cos(x*l/a)+(G^2*J^2*x/(a*k))*sin(x*l/a),[(1e-20)*pi*a/l,(0.5)*pi*a/l]);
                            else
                            omega=fzero(@(x) (I*x^2*G*J/k-G*J)*cos(x*l/a)+(I*x*a+G^2*J^2*x/(a*k))*sin(x*l/a),[(n-1.5)*pi*a/l,(n-0.5)*pi*a/l]);
                            end
                            a2=sqrt(1/(1+(G*J*omega/(k*a))^2));
                            a1=sqrt(1-a2^2);
                    end
                    %% output
                    app.radsEditField_8.Value=omega;
                    rod_x = linspace(0,l,300); % 主振型横坐标离散
                    rod_y = a1.*cos(omega.*rod_x./a)+a2.*sin(omega.*rod_x./a);
                    plot(app.UIAxes2_2,rod_x,rod_y);
                case "惯性约束"
                    I=app.EditField5_2.Value;
                    switch app.DropDown_4.Value
                        case "固定端"
                        omega=fzero(@(x) sin(x*l/a)-cos(x*l/a)*G*J/(I*a*x),[(n-1+1e-20)*pi*a/l,(n-0.5)*pi*a/l]);
                        a1=0;
                        a2=1;
                        %% output
                        app.radsEditField_8.Value=omega;
                        rod_x = linspace(0,l,300); % 主振型横坐标离散
                        rod_y = a1.*cos(omega.*rod_x./a)+a2.*sin(omega.*rod_x./a);
                        rod_y1 = flip(rod_y);
                        plot(app.UIAxes2_2,rod_x,rod_y1);
                        case "自由端"
                        a1=1;
                        a2=0;
                        if n ==1
                            omega=fzero(@(x) a*I*x*cos(x*l/a)+sin(x*l/a)*G*J,[0*pi*a/l,0.5*pi*a/l]);
                        else
                            omega=fzero(@(x) a*I*x*cos(x*l/a)+sin(x*l/a)*G*J,[(n-1.5)*pi*a/l,(n-0.5)*pi*a/l]);
                        end
                        %% output
                        app.radsEditField_8.Value=omega;
                        rod_x = linspace(0,l,300); % 主振型横坐标离散
                        rod_y = a1.*cos(omega.*rod_x./a)+a2.*sin(omega.*rod_x./a);
                        rod_y1 = flip(rod_y);
                        plot(app.UIAxes2_2,rod_x,rod_y1);
                        case "弹性约束"
                        k=app.EditField_5.Value;
                        if n==1
                            omega=0;
                        else
                            omega=fzero(@(x) (I*x^2*G*J/k-G*J)*cos(x*l/a)+(G*x*a+G^2*J^2*x/(a*k))*sin(x*l/a),[(n-2+1e-20)*pi*a/l,(n-1)*pi*a/l]);
                        end
                        a2=sqrt(1/(1+(G*J*omega/(k*a))^2));
                        a1=sqrt(1-a2^2);
                        %% output
                        app.radsEditField_8.Value=omega;
                        rod_x = linspace(0,l,300); % 主振型横坐标离散
                        rod_y = a1.*cos(omega.*rod_x./a)+a2.*sin(omega.*rod_x./a);
                        rod_y1 = flip(rod_y);
                        plot(app.UIAxes2_2,rod_x,rod_y1);
                        case "惯性约束"
                        I1=app.EditField5_2.Value;
                        I2=app.EditField_5.Value;
                        if n==1
                            omega=0;
                        else
                            omega=fzero(@(x) (-1*G*J*((I1+I2)/I1))*cos(x*l/a)+(I2*x*a-(G*J)^2/(I1*x*a))*sin(x*l/a),[(n-2+1e-20)*pi*a/l,(n-1)*pi*a/l]);  
                        end
                        a2=-sqrt(1/(1+(G*J/(I1*omega*a))^2));
                        a1=1*sqrt(1-a2^2);
                        %% output
                        app.radsEditField_8.Value=omega;
                        rod_x = linspace(0,l,300); % 主振型横坐标离散
                        rod_y = a1.*cos(omega.*rod_x./a)+a2.*sin(omega.*rod_x./a);
                        plot(app.UIAxes2_2,rod_x,rod_y);
                    end
            end
%             %% output
%             app.radsEditField_8.Value=omega;
%             shaft_x = linspace(0,l,300); % 主振型横坐标离散
%             shaft_y = a1.*cos(omega.*shaft_x./a)+a2.*sin(omega.*shaft_x./a);
%             plot(app.UIAxes2_2,shaft_x,shaft_y);
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            switch value
                case "固定端"
                    app.Image4_4.ImageSource='轴-固定端-左.jpg';
                    app.kgm2Label.Visible='off';
                    app.NmradLabel.Visible='off';
                    app.EditField5_2.Visible='off';
                case "自由端"
                    app.Image4_4.ImageSource='轴-自由端-左.jpg';
                    app.kgm2Label.Visible='off';
                    app.NmradLabel.Visible='off';
                    app.EditField5_2.Visible='off';
                case "弹性约束"
                    app.Image4_4.ImageSource='轴-弹性约束-左.jpg';
                    app.kgm2Label.Visible='off';
                    app.NmradLabel.Visible='on';
                    app.EditField5_2.Visible='on';
                case "惯性约束"
                    app.Image4_4.ImageSource='轴-惯性约束-左.jpg';
                    app.kgm2Label.Visible='on';
                    app.NmradLabel.Visible='off';
                    app.EditField5_2.Visible='on';
            end
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
            value = app.DropDown_4.Value;
            switch value
                case "固定端"
                    app.Image4_5.ImageSource='轴-固定端-右.jpg';
                    app.kgm2Label_2.Visible='off';
                    app.NmradLabel_2.Visible='off';
                    app.EditField_5.Visible='off';
                case "自由端"
                    app.Image4_5.ImageSource='轴-自由端-右.jpg';
                    app.kgm2Label_2.Visible='off';
                    app.NmradLabel_2.Visible='off';
                    app.EditField_5.Visible='off';
                case "弹性约束"
                    app.Image4_5.ImageSource='轴-弹性约束-右.jpg';
                    app.kgm2Label_2.Visible='off';
                    app.NmradLabel_2.Visible='on';
                    app.EditField_5.Visible='on';
                case "惯性约束"
                    app.Image4_5.ImageSource='轴-惯性约束-右.jpg';
                    app.kgm2Label_2.Visible='on';
                    app.NmradLabel_2.Visible='off';
                    app.EditField_5.Visible='on';
            end
        end

        % Button pushed function: CalculateButton_4
        function CalculateButton_4Pushed(app, event)
        %%参数读入
        l=app.LmEditField_3.Value; %梁长度，单位：m
        I=app.Im4EditField.Value; %梁关于中性面惯性矩，单位：m^4
        E=app.EGPaEditField_2.Value; %弹性模量，单位：GPa
        rho=app.kgm3EditField_3.Value; %密度，单位：kg/m^3
        A=app.Am2EditField_2.Value; %截面积，单位：m^2
        n=app.Spinner_3.Value; %求解阶数
        a2=E*I/(rho*A);%波动方程中的a^2
        switch app.DropDown_5.Value
            case "固定端"
            switch app.CODE_NAME.Value
                case "固定端"
                    beta = fzero(@(x) sin(x*l)^2-sinh(x*l)^2+(cosh(x*l)-cos(x*l))^2,[n*pi/l,(n+1)*pi/l]); 
                    c2=sqrt(1/(2*(1+((cosh(beta*l)-cos(beta*l))/(sin(beta*l)-sinh(beta*l)))^2)));
                    c1=c2*((cosh(beta*l)-cos(beta*l))/(sin(beta*l)-sinh(beta*l)));
                    c3=-1*c1;
                    c4=-1*c2;     
                case "自由端"
                    beta = fzero(@(x) sin(x*l)^2-sinh(x*l)^2+(cosh(x*l)+cos(x*l))^2,[(n-1)*pi/l,(n)*pi/l]); 
                    c2=sqrt(1/(2*(1+((cosh(beta*l)-cos(beta*l))/(sin(beta*l)-sinh(beta*l)))^2)));
                    c1=c2*((-cosh(beta*l)-cos(beta*l))/(sin(beta*l)+sinh(beta*l)));
                    c3=-1*c1;
                    c4=-1*c2;   
                case "铰支端"
                    beta = fzero(@(x) (sin(x*l)-sinh(x*l))*(cos(x*l)+cosh(x*l))+(sin(x*l)+sinh(x*l))*(cosh(x*l)-cos(x*l)),[(n)*pi/l,(n+1)*pi/l]); 
                    c2=sqrt(1/(2*(1+((cosh(beta*l)-cos(beta*l))/(sin(beta*l)-sinh(beta*l)))^2)));
                    c1=c2*((cosh(beta*l)-cos(beta*l))/(sin(beta*l)-sinh(beta*l)));
                    c3=-1*c1;
                    c4=-1*c2;   
                case "弹性约束"
                    k=app.EditField_6.Value;
                    kt=app.EditField_9.Value;
                    syms beta1 c1 c2 c3 c4
                    [beta1,c1,c2,c3,c4] = vpasolve([c1^2+c2^2+c3^2+c4^2==1, ...
                        c1+c3==0, ...
                        c2+c4==0, ...
                        E*I*beta1^2*(-c1*sin(beta1*l)-c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))+kt*beta1*(c1*cos(beta1*l)-c2*sin(beta1*l)+c3*cosh(beta1*l)+c4*sinh(beta1*l))==0 ...
                        E*I*beta1^3*(-c1*cos(beta1*l)+c2*sin(beta1*l)+c3*cosh(beta1*l)+c4*sinh(beta1*l))-k*(c1*sin(beta1*l)+c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0], ...
                        [beta1,c1,c2,c3,c4],[(n-0.5)*pi/l,(n+0.5)*pi/l;-1,1;-1,1;-1,1;-1,1]);
                    beta=double(beta1);
                    c1=double(c1);
                    c2=double(c2);
                    c3=double(c3);
                    c4=double(c4);
                case "惯性约束"
                    m=app.EditField_6.Value;
                    syms beta1 c1 c2 c3 c4
                    [beta1,c1,c2,c3,c4] = vpasolve([c1^2+c2^2+c3^2+c4^2==1, ...
                        c1+c3==0, ...
                        c2+c4==0, ...
                        E*I*beta1^2*(-c1*sin(beta1*l)-c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0 ...
                        E*I*beta1^3*(-c1*cos(beta1*l)+c2*sin(beta1*l)+c3*cosh(beta1*l)+c4*sinh(beta1*l))+m*a2*beta1^4*(c1*sin(beta1*l)+c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0], ...
                        [beta1,c1,c2,c3,c4],[(n-1)*pi/l,(n)*pi/l;-1,1;-1,1;-1,1;-1,1]);
                    beta=double(beta1);
                    c1=double(c1);
                    c2=double(c2);
                    c3=double(c3);
                    c4=double(c4);
            end
            % output
            omega=sqrt(beta^4*E*I/(rho*A));
            app.radsEditField_9.Value=omega;
            beam_x = linspace(0,l,500); % 主振型横坐标离散
            beam_y = c1.*sin(beta.*beam_x)+c2.*cos(beta.*beam_x)+c3.*sinh(beta.*beam_x)+c4.*cosh(beta.*beam_x);  
            plot(app.UIAxes2_3,beam_x,beam_y);        
            case "自由端"
            switch app.CODE_NAME.Value
                case "固定端"%对称
                        beta = fzero(@(x) sin(x*l)^2-sinh(x*l)^2+(cosh(x*l)+cos(x*l))^2,[(n-1)*pi/l,(n)*pi/l]); 
                        c2=sqrt(1/(2*(1+((cosh(beta*l)-cos(beta*l))/(sin(beta*l)-sinh(beta*l)))^2)));
                        c1=c2*((-cosh(beta*l)-cos(beta*l))/(sin(beta*l)+sinh(beta*l)));
                        c3=-1*c1;
                        c4=-1*c2;   
                        % output
                        omega=sqrt(beta^4*E*I/(rho*A));
                        app.radsEditField_9.Value=omega;
                        beam_x = linspace(0,l,500); % 主振型横坐标离散
                        beam_y = c1.*sin(beta.*beam_x)+c2.*cos(beta.*beam_x)+c3.*sinh(beta.*beam_x)+c4.*cosh(beta.*beam_x);  
                        beam_y = flip(beam_y);
                        plot(app.UIAxes2_3,beam_x,beam_y);  
                case "自由端"
%                     syms beta1 c1 c2 c3 c4
%                     [beta1,c1,c2,c3,c4] = vpasolve([c1^2+c2^2+c3^2+c4^2==1, ...
%                         E*I*beta1^2*(-c1*sin(beta1*0)-c2*cos(beta1*0)+c3*sinh(beta1*0)+c4*cosh(beta1*0))==0, ...
%                         E*I*beta1^3*(-c1*cos(beta1*0)+c2*sin(beta1*0)+c3*cosh(beta1*0)+c4*sinh(beta1*0))==0, ...
%                         E*I*beta1^2*(-c1*sin(beta1*l)-c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0, ...
%                         E*I*beta1^3*(-c1*cos(beta1*l)+c2*sin(beta1*l)+c3*cosh(beta1*l)+c4*sinh(beta1*l))==0], ...
%                         [beta1,c1,c2,c3,c4],[(n)*pi/l,(n+1)*pi/l;-1,1;-1,1;-1,1;-1,1]);
%                     beta=double(beta1);
%                     c1=double(c1);
%                     c2=double(c2);
%                     c3=double(c3);
%                     c4=double(c4);
                    if n == 1 
                        plot(app.UIAxes2_3,[0 l],[1 1]);
                    elseif n == 2
                        plot(app.UIAxes2_3,[0 l],[-1 1]);
                    else
                        beta = fzero(@(x) (cosh(x*l)-cos(x*l))^2+(sin(x*l))^2-(sinh(x*l))^2,[(n-2)*pi/l,(n-1)*pi/l]); 
                        c2=sqrt(1/(2*(1+((cosh(beta*l)-cos(beta*l))/(sin(beta*l)-sinh(beta*l)))^2)));
                        c1=c2*((cosh(beta*l)-cos(beta*l))/(sin(beta*l)-sinh(beta*l)));
                        c3=c1;
                        c4=c2;  
                        % output
                        omega=sqrt(beta^4*E*I/(rho*A));
                        app.radsEditField_9.Value=omega;
                        beam_x = linspace(0,l,500); % 主振型横坐标离散
                        beam_y = c1.*sin(beta.*beam_x)+c2.*cos(beta.*beam_x)+c3.*sinh(beta.*beam_x)+c4.*cosh(beta.*beam_x);  
                        plot(app.UIAxes2_3,beam_x,beam_y);
                    end
                case "铰支端"
%                     syms beta1 c1 c2 c3 c4
%                     [beta1,c1,c2,c3,c4] = vpasolve([c1^2+c2^2+c3^2+c4^2==1, ...
%                         E*I*beta1^2*(-c1*sin(beta1*0)-c2*cos(beta1*0)+c3*sinh(beta1*0)+c4*cosh(beta1*0))==0, ...
%                         E*I*beta1^3*(-c1*cos(beta1*0)+c2*sin(beta1*0)+c3*cosh(beta1*0)+c4*sinh(beta1*0))==0, ...
%                         c1*sin(beta1*l)+c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l)==0,...
%                         E*I*beta1^2*(-c1*sin(beta1*l)-c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0], ...
%                         [beta1,c1,c2,c3,c4],[(n-1)*pi/l,(n)*pi/l;-1,1;-1,1;-1,1;-1,1]);
%                         beta=double(beta1);
%                     c1=double(c1);
%                     c2=double(c2);
%                     c3=double(c3);
%                     c4=double(c4);
                    if n == 1
                        plot(app.UIAxes2_3,[0 l],[1 0]);
                    else
                        beta = fzero(@(x) (sinh(x*l)-sin(x*l))*(cos(x*l)+cosh(x*l))+(sin(x*l)+sinh(x*l))*(cos(x*l)-cosh(x*l)),[(n-1)*pi/l,(n)*pi/l]); 
                        c2=sqrt(1/(2*(1+((cosh(beta*l)-cos(beta*l))/(sin(beta*l)-sinh(beta*l)))^2)));
                        c1=c2*((cosh(beta*l)-cos(beta*l))/(sin(beta*l)-sinh(beta*l)));
                        c3=c1;
                        c4=c2;  
                        % output
                        omega=sqrt(beta^4*E*I/(rho*A));
                        app.radsEditField_9.Value=omega;
                        beam_x = linspace(0,l,500); % 主振型横坐标离散
                        beam_y = c1.*sin(beta.*beam_x)+c2.*cos(beta.*beam_x)+c3.*sinh(beta.*beam_x)+c4.*cosh(beta.*beam_x);  
                        plot(app.UIAxes2_3,beam_x,beam_y);  
                    end
                case "弹性约束"
                    k=app.EditField_6.Value;
                    kt=app.EditField_9.Value;
                    syms beta1 c1 c2 c3 c4
                    [beta1,c1,c2,c3,c4] = vpasolve([c1^2+c2^2+c3^2+c4^2==1, ...
                        E*I*beta1^2*(-c1*sin(beta1*0)-c2*cos(beta1*0)+c3*sinh(beta1*0)+c4*cosh(beta1*0))==0, ...
                        E*I*beta1^3*(-c1*cos(beta1*0)+c2*sin(beta1*0)+c3*cosh(beta1*0)+c4*sinh(beta1*0))==0, ...
                        E*I*beta1^2*(-c1*sin(beta1*l)-c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))+kt*beta1*(c1*cos(beta1*l)-c2*sin(beta1*l)+c3*cosh(beta1*l)+c4*sinh(beta1*l))==0 ...
                        E*I*beta1^3*(-c1*cos(beta1*l)+c2*sin(beta1*l)+c3*cosh(beta1*l)+c4*sinh(beta1*l))-k*(c1*sin(beta1*l)+c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0], ...
                        [beta1,c1,c2,c3,c4],[(n-1.4)*pi/l,(n-0.4)*pi/l;-1,1;-1,1;-1,1;-1,1]);
                    beta=double(beta1);
                    c1=double(c1);
                    c2=double(c2);
                    c3=double(c3);
                    c4=double(c4);
                    % output
                    omega=sqrt(beta^4*E*I/(rho*A));
                    app.radsEditField_9.Value=omega;
                    beam_x = linspace(0,l,500); % 主振型横坐标离散
                    beam_y = c1.*sin(beta.*beam_x)+c2.*cos(beta.*beam_x)+c3.*sinh(beta.*beam_x)+c4.*cosh(beta.*beam_x);  
                    plot(app.UIAxes2_3,beam_x,beam_y);  
                case "惯性约束"
                    if n == 1 
                        plot(app.UIAxes2_3,[0 l],[1 1]);
                    elseif n == 2
                        plot(app.UIAxes2_3,[0 l],[-1 1]);
                    else
                        m=app.EditField_6.Value;
                        syms beta1 c1 c2 c3 c4
                        [beta1,c1,c2,c3,c4] = vpasolve([c1^2+c2^2+c3^2+c4^2==1, ...
                            E*I*beta1^2*(-c1*sin(beta1*0)-c2*cos(beta1*0)+c3*sinh(beta1*0)+c4*cosh(beta1*0))==0, ...
                            E*I*beta1^3*(-c1*cos(beta1*0)+c2*sin(beta1*0)+c3*cosh(beta1*0)+c4*sinh(beta1*0))==0, ...
                            E*I*beta1^2*(-c1*sin(beta1*l)-c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0 ...
                            E*I*beta1^3*(-c1*cos(beta1*l)+c2*sin(beta1*l)+c3*cosh(beta1*l)+c4*sinh(beta1*l))+m*a2*beta1^4*(c1*sin(beta1*l)+c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0], ...
                            [beta1,c1,c2,c3,c4],[(n-2)*pi/l,(n-1)*pi/l;-1,1;-1,1;-1,1;-1,1]);
                        beta=double(beta1);
                        c1=double(c1);
                        c2=double(c2);
                        c3=double(c3);
                        c4=double(c4); 
                        % output
                        omega=sqrt(beta^4*E*I/(rho*A));
                        app.radsEditField_9.Value=omega;
                        beam_x = linspace(0,l,500); % 主振型横坐标离散
                        beam_y = c1.*sin(beta.*beam_x)+c2.*cos(beta.*beam_x)+c3.*sinh(beta.*beam_x)+c4.*cosh(beta.*beam_x);  
                        plot(app.UIAxes2_3,beam_x,beam_y);  
                    end
            end    
            case "铰支端"
            switch app.CODE_NAME.Value
                case "固定端"
                    beta = fzero(@(x) (sin(x*l)-sinh(x*l))*(cos(x*l)+cosh(x*l))+(sin(x*l)+sinh(x*l))*(cosh(x*l)-cos(x*l)),[(n)*pi/l,(n+1)*pi/l]); 
                    c2=sqrt(1/(2*(1+((cosh(beta*l)-cos(beta*l))/(sin(beta*l)-sinh(beta*l)))^2)));
                    c1=c2*((cosh(beta*l)-cos(beta*l))/(sin(beta*l)-sinh(beta*l)));
                    c3=-1*c1;
                    c4=-1*c2;  
                    % output
                    omega=sqrt(beta^4*E*I/(rho*A));
                    app.radsEditField_9.Value=omega;
                    beam_x = linspace(0,l,500); % 主振型横坐标离散
                    beam_y = c1.*sin(beta.*beam_x)+c2.*cos(beta.*beam_x)+c3.*sinh(beta.*beam_x)+c4.*cosh(beta.*beam_x);  
                    beam_y = flip(beam_y);
                    plot(app.UIAxes2_3,beam_x,beam_y);  
                case "自由端"
                    if n == 1
                        plot(app.UIAxes2_3,[0 l],[0 1]);
                    else
                    syms beta1 c1 c2 c3 c4
                    [beta1,c1,c2,c3,c4] = vpasolve([c1^2+c2^2+c3^2+c4^2==1, ...
                        E*I*beta1^2*(-c1*sin(beta1*0)-c2*cos(beta1*0)+c3*sinh(beta1*0)+c4*cosh(beta1*0))==0, ...
                        E*I*beta1^3*(-c1*cos(beta1*0)+c2*sin(beta1*0)+c3*cosh(beta1*0)+c4*sinh(beta1*0))==0, ...
                        c1*sin(beta1*l)+c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l)==0,...
                        E*I*beta1^2*(-c1*sin(beta1*l)-c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0], ...
                        [beta1,c1,c2,c3,c4],[(n-1)*pi/l,(n)*pi/l;-1,1;-1,1;-1,1;-1,1]);
                    beta=double(beta1);
                    c1=double(c1);
                    c2=double(c2);
                    c3=double(c3);
                    c4=double(c4);
                    % output
                    omega=sqrt(beta^4*E*I/(rho*A));
                    app.radsEditField_9.Value=omega;
                    beam_x = linspace(0,l,500); % 主振型横坐标离散
                    beam_y = c1.*sin(beta.*beam_x)+c2.*cos(beta.*beam_x)+c3.*sinh(beta.*beam_x)+c4.*cosh(beta.*beam_x);  
                    beam_y = flip(beam_y);
                    plot(app.UIAxes2_3,beam_x,beam_y);  
                    end
                case "铰支端"
                    syms beta1 c1 c2 c3 c4
                    [beta1,c1,c2,c3,c4] = vpasolve([c1^2+c2^2+c3^2+c4^2==1, ...
                        c1*sin(beta1*0)+c2*cos(beta1*0)+c3*sinh(beta1*0)+c4*cosh(beta1*0)==0,...
                        E*I*beta1^2*(-c1*sin(beta1*0)-c2*cos(beta1*0)+c3*sinh(beta1*0)+c4*cosh(beta1*0))==0, ...
                        c1*sin(beta1*l)+c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l)==0,...
                        E*I*beta1^2*(-c1*sin(beta1*l)-c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0], ...
                        [beta1,c1,c2,c3,c4],[(n-0.5)*pi/l,(n+0.5)*pi/l;-1,1;-1,1;-1,1;-1,1]);
                    beta=double(beta1);
                    c1=double(c1);
                    c2=double(c2);
                    c3=double(c3);
                    c4=double(c4);
                    % output
                    omega=sqrt(beta^4*E*I/(rho*A));
                    app.radsEditField_9.Value=omega;
                    beam_x = linspace(0,l,500); % 主振型横坐标离散
                    beam_y = c1.*sin(beta.*beam_x)+c2.*cos(beta.*beam_x)+c3.*sinh(beta.*beam_x)+c4.*cosh(beta.*beam_x);  
                    plot(app.UIAxes2_3,beam_x,beam_y);  
                case "弹性约束"
                    k=app.EditField_6.Value;
                    kt=app.EditField_9.Value;
                    syms beta1 c1 c2 c3 c4
                    [beta1,c1,c2,c3,c4] = vpasolve([c1^2+c2^2+c3^2+c4^2==1, ...
                        c1*sin(beta1*0)+c2*cos(beta1*0)+c3*sinh(beta1*0)+c4*cosh(beta1*0)==0,...
                        E*I*beta1^2*(-c1*sin(beta1*0)-c2*cos(beta1*0)+c3*sinh(beta1*0)+c4*cosh(beta1*0))==0, ...
                        E*I*beta1^2*(-c1*sin(beta1*l)-c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))+kt*beta1*(c1*cos(beta1*l)-c2*sin(beta1*l)+c3*cosh(beta1*l)+c4*sinh(beta1*l))==0 ...
                        E*I*beta1^3*(-c1*cos(beta1*l)+c2*sin(beta1*l)+c3*cosh(beta1*l)+c4*sinh(beta1*l))-k*(c1*sin(beta1*l)+c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0], ...
                        [beta1,c1,c2,c3,c4],[(n-0.5)*pi/l,(n+0.5)*pi/l;-1,1;-1,1;-1,1;-1,1]);
                    beta=double(beta1);
                    c1=double(c1);
                    c2=double(c2);
                    c3=double(c3);
                    c4=double(c4);
                    % output
                    omega=sqrt(beta^4*E*I/(rho*A));
                    app.radsEditField_9.Value=omega;
                    beam_x = linspace(0,l,500); % 主振型横坐标离散
                    beam_y = c1.*sin(beta.*beam_x)+c2.*cos(beta.*beam_x)+c3.*sinh(beta.*beam_x)+c4.*cosh(beta.*beam_x);  
                    plot(app.UIAxes2_3,beam_x,beam_y); 
                case "惯性约束"
                    if n == 1
                        plot(app.UIAxes2_3,[0 l],[0 1]);
                    else
                        m=app.EditField_6.Value;
                        syms beta1 c1 c2 c3 c4
                        [beta1,c1,c2,c3,c4] = vpasolve([c1^2+c2^2+c3^2+c4^2==1, ...
                            c1*sin(beta1*0)+c2*cos(beta1*0)+c3*sinh(beta1*0)+c4*cosh(beta1*0)==0,...
                            E*I*beta1^2*(-c1*sin(beta1*0)-c2*cos(beta1*0)+c3*sinh(beta1*0)+c4*cosh(beta1*0))==0, ...
                            E*I*beta1^2*(-c1*sin(beta1*l)-c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0 ...
                            E*I*beta1^3*(-c1*cos(beta1*l)+c2*sin(beta1*l)+c3*cosh(beta1*l)+c4*sinh(beta1*l))+m*a2*beta1^4*(c1*sin(beta1*l)+c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0], ...
                            [beta1,c1,c2,c3,c4],[(n-1.5)*pi/l,(n-0.5)*pi/l;-1,1;-1,1;-1,1;-1,1]);
                        beta=double(beta1);
                        c1=double(c1);
                        c2=double(c2);
                        c3=double(c3);
                        c4=double(c4);
                        % output
                        omega=sqrt(beta^4*E*I/(rho*A));
                        app.radsEditField_9.Value=omega;
                        beam_x = linspace(0,l,500); % 主振型横坐标离散
                        beam_y = c1.*sin(beta.*beam_x)+c2.*cos(beta.*beam_x)+c3.*sinh(beta.*beam_x)+c4.*cosh(beta.*beam_x);  
                        plot(app.UIAxes2_3,beam_x,beam_y); 
                    end
            end    
            case "弹性约束"
            switch app.CODE_NAME.Value
                case "固定端"
                    k=app.EditField_7.Value;
                    kt=app.EditField_8.Value;
                    syms beta1 c1 c2 c3 c4
                    [beta1,c1,c2,c3,c4] = vpasolve([c1^2+c2^2+c3^2+c4^2==1, ...
                        c1+c3==0, ...
                        c2+c4==0, ...
                        E*I*beta1^2*(-c1*sin(beta1*l)-c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))+kt*beta1*(c1*cos(beta1*l)-c2*sin(beta1*l)+c3*cosh(beta1*l)+c4*sinh(beta1*l))==0 ...
                        E*I*beta1^3*(-c1*cos(beta1*l)+c2*sin(beta1*l)+c3*cosh(beta1*l)+c4*sinh(beta1*l))-k*(c1*sin(beta1*l)+c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0], ...
                        [beta1,c1,c2,c3,c4],[(n-0.5)*pi/l,(n+0.5)*pi/l;-1,1;-1,1;-1,1;-1,1]);
                    beta=double(beta1);
                    c1=double(c1);
                    c2=double(c2);
                    c3=double(c3);
                    c4=double(c4);
                    % output
                    omega=sqrt(beta^4*E*I/(rho*A));
                    app.radsEditField_9.Value=omega;
                    beam_x = linspace(0,l,500); % 主振型横坐标离散
                    beam_y = c1.*sin(beta.*beam_x)+c2.*cos(beta.*beam_x)+c3.*sinh(beta.*beam_x)+c4.*cosh(beta.*beam_x);  
                    beam_y = flip(beam_y);
                    plot(app.UIAxes2_3,beam_x,beam_y); 
                case "自由端"
                    k=app.EditField_6.Value;
                    kt=app.EditField_9.Value;
                    syms beta1 c1 c2 c3 c4
                    [beta1,c1,c2,c3,c4] = vpasolve([c1^2+c2^2+c3^2+c4^2==1, ...
                        E*I*beta1^2*(-c1*sin(beta1*0)-c2*cos(beta1*0)+c3*sinh(beta1*0)+c4*cosh(beta1*0))==0, ...
                        E*I*beta1^3*(-c1*cos(beta1*0)+c2*sin(beta1*0)+c3*cosh(beta1*0)+c4*sinh(beta1*0))==0, ...
                        E*I*beta1^2*(-c1*sin(beta1*l)-c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))+kt*beta1*(c1*cos(beta1*l)-c2*sin(beta1*l)+c3*cosh(beta1*l)+c4*sinh(beta1*l))==0 ...
                        E*I*beta1^3*(-c1*cos(beta1*l)+c2*sin(beta1*l)+c3*cosh(beta1*l)+c4*sinh(beta1*l))-k*(c1*sin(beta1*l)+c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0], ...
                        [beta1,c1,c2,c3,c4],[(n-1.4)*pi/l,(n-0.4)*pi/l;-1,1;-1,1;-1,1;-1,1]);
                    beta=double(beta1);
                    c1=double(c1);
                    c2=double(c2);
                    c3=double(c3);
                    c4=double(c4);
                    % output
                    omega=sqrt(beta^4*E*I/(rho*A));
                    app.radsEditField_9.Value=omega;
                    beam_x = linspace(0,l,500); % 主振型横坐标离散
                    beam_y = c1.*sin(beta.*beam_x)+c2.*cos(beta.*beam_x)+c3.*sinh(beta.*beam_x)+c4.*cosh(beta.*beam_x);  
                    beam_y = flip(beam_y);
                    plot(app.UIAxes2_3,beam_x,beam_y); 
                case "铰支端"
                    k=app.EditField_7.Value;
                    kt=app.EditField_8.Value;
                    syms beta1 c1 c2 c3 c4
                    [beta1,c1,c2,c3,c4] = vpasolve([c1^2+c2^2+c3^2+c4^2==1, ...
                        c1*sin(beta1*0)+c2*cos(beta1*0)+c3*sinh(beta1*0)+c4*cosh(beta1*0)==0,...
                        E*I*beta1^2*(-c1*sin(beta1*0)-c2*cos(beta1*0)+c3*sinh(beta1*0)+c4*cosh(beta1*0))==0, ...
                        E*I*beta1^2*(-c1*sin(beta1*l)-c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))+kt*beta1*(c1*cos(beta1*l)-c2*sin(beta1*l)+c3*cosh(beta1*l)+c4*sinh(beta1*l))==0 ...
                        E*I*beta1^3*(-c1*cos(beta1*l)+c2*sin(beta1*l)+c3*cosh(beta1*l)+c4*sinh(beta1*l))-k*(c1*sin(beta1*l)+c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0], ...
                        [beta1,c1,c2,c3,c4],[(n-0.5)*pi/l,(n+0.5)*pi/l;-1,1;-1,1;-1,1;-1,1]);
                    beta=double(beta1);
                    c1=double(c1);
                    c2=double(c2);
                    c3=double(c3);
                    c4=double(c4);
                    % output
                    omega=sqrt(beta^4*E*I/(rho*A));
                    app.radsEditField_9.Value=omega;
                    beam_x = linspace(0,l,500); % 主振型横坐标离散
                    beam_y = c1.*sin(beta.*beam_x)+c2.*cos(beta.*beam_x)+c3.*sinh(beta.*beam_x)+c4.*cosh(beta.*beam_x);  
                    beam_y = flip(beam_y);
                    plot(app.UIAxes2_3,beam_x,beam_y); 
                case "弹性约束"
                    k_l=app.EditField_7.Value;
                    kt_l=app.EditField_8.Value;
                    k_r=app.EditField_6.Value;
                    kt_r=app.EditField_9.Value;
                        syms beta1 c1 c2 c3 c4
                        if n < 5
                            [beta1,c1,c2,c3,c4] = vpasolve([c1^2+c2^2+c3^2+c4^2==1, ...
                            E*I*beta1^2*(-c1*sin(beta1*0)-c2*cos(beta1*0)+c3*sinh(beta1*0)+c4*cosh(beta1*0))-kt_l*beta1*(c1*cos(beta1*0)-c2*sin(beta1*0)+c3*cosh(beta1*0)+c4*sinh(beta1*0))==0 ...
                            E*I*beta1^3*(-c1*cos(beta1*0)+c2*sin(beta1*0)+c3*cosh(beta1*0)+c4*sinh(beta1*0))+k_l*(c1*sin(beta1*0)+c2*cos(beta1*0)+c3*sinh(beta1*0)+c4*cosh(beta1*0))==0, ...
                            E*I*beta1^2*(-c1*sin(beta1*l)-c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))+kt_r*beta1*(c1*cos(beta1*l)-c2*sin(beta1*l)+c3*cosh(beta1*l)+c4*sinh(beta1*l))==0 ...
                            E*I*beta1^3*(-c1*cos(beta1*l)+c2*sin(beta1*l)+c3*cosh(beta1*l)+c4*sinh(beta1*l))-k_r*(c1*sin(beta1*l)+c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0], ...
                            [beta1,c1,c2,c3,c4],[(n-0.5)*pi/l,(n+0.5)*pi/l;-1,1;-1,1;-1,1;-1,1]);
                        else
                            [beta1,c1,c2,c3,c4] = vpasolve([c1^2+c2^2+c3^2+c4^2==1, ...
                            E*I*beta1^2*(-c1*sin(beta1*0)-c2*cos(beta1*0)+c3*sinh(beta1*0)+c4*cosh(beta1*0))-kt_l*beta1*(c1*cos(beta1*0)-c2*sin(beta1*0)+c3*cosh(beta1*0)+c4*sinh(beta1*0))==0 ...
                            E*I*beta1^3*(-c1*cos(beta1*0)+c2*sin(beta1*0)+c3*cosh(beta1*0)+c4*sinh(beta1*0))+k_l*(c1*sin(beta1*0)+c2*cos(beta1*0)+c3*sinh(beta1*0)+c4*cosh(beta1*0))==0, ...
                            E*I*beta1^2*(-c1*sin(beta1*l)-c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))+kt_r*beta1*(c1*cos(beta1*l)-c2*sin(beta1*l)+c3*cosh(beta1*l)+c4*sinh(beta1*l))==0 ...
                            E*I*beta1^3*(-c1*cos(beta1*l)+c2*sin(beta1*l)+c3*cosh(beta1*l)+c4*sinh(beta1*l))-k_r*(c1*sin(beta1*l)+c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0], ...
                            [beta1,c1,c2,c3,c4],[(n-1)*pi/l,(n)*pi/l;-1,1;-1,1;-1,1;-1,1]);
                        end
                        beta=double(beta1);
                        c1=double(c1);
                        c2=double(c2);
                        c3=double(c3);
                        c4=double(c4);
                    % output
                    omega=sqrt(beta^4*E*I/(rho*A));
                    app.radsEditField_9.Value=omega;
                    beam_x = linspace(0,l,500); % 主振型横坐标离散
                    beam_y = c1.*sin(beta.*beam_x)+c2.*cos(beta.*beam_x)+c3.*sinh(beta.*beam_x)+c4.*cosh(beta.*beam_x);  
                    plot(app.UIAxes2_3,beam_x,beam_y); 
                case "惯性约束"
                    k_l=app.EditField_7.Value;
                    kt_l=app.EditField_8.Value;
                    m=app.EditField_6.Value;
                    syms beta1 c1 c2 c3 c4
                    [beta1,c1,c2,c3,c4] = vpasolve([c1^2+c2^2+c3^2+c4^2==1, ...
                        E*I*beta1^2*(-c1*sin(beta1*0)-c2*cos(beta1*0)+c3*sinh(beta1*0)+c4*cosh(beta1*0))-kt_l*beta1*(c1*cos(beta1*0)-c2*sin(beta1*0)+c3*cosh(beta1*0)+c4*sinh(beta1*0))==0 ...
                        E*I*beta1^3*(-c1*cos(beta1*0)+c2*sin(beta1*0)+c3*cosh(beta1*0)+c4*sinh(beta1*0))+k_l*(c1*sin(beta1*0)+c2*cos(beta1*0)+c3*sinh(beta1*0)+c4*cosh(beta1*0))==0, ...
                        E*I*beta1^2*(-c1*sin(beta1*l)-c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0 ...
                        E*I*beta1^3*(-c1*cos(beta1*l)+c2*sin(beta1*l)+c3*cosh(beta1*l)+c4*sinh(beta1*l))+m*a2*beta1^4*(c1*sin(beta1*l)+c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0], ...
                        [beta1,c1,c2,c3,c4],[(n-2)*pi/l,(n-1)*pi/l;-1,1;-1,1;-1,1;-1,1]);
                    beta=double(beta1);
                    c1=double(c1);
                    c2=double(c2);
                    c3=double(c3);
                    c4=double(c4);
                    % output
                    omega=sqrt(beta^4*E*I/(rho*A));
                    app.radsEditField_9.Value=omega;
                    beam_x = linspace(0,l,500); % 主振型横坐标离散
                    beam_y = c1.*sin(beta.*beam_x)+c2.*cos(beta.*beam_x)+c3.*sinh(beta.*beam_x)+c4.*cosh(beta.*beam_x);  
                    plot(app.UIAxes2_3,beam_x,beam_y); 
            end    
            case "惯性约束"
            switch app.CODE_NAME.Value
                case "固定端"
                    m=app.EditField_7.Value;
                    syms beta1 c1 c2 c3 c4
                    [beta1,c1,c2,c3,c4] = vpasolve([c1^2+c2^2+c3^2+c4^2==1, ...
                        c1+c3==0, ...
                        c2+c4==0, ...
                        E*I*beta1^2*(-c1*sin(beta1*l)-c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0 ...
                        E*I*beta1^3*(-c1*cos(beta1*l)+c2*sin(beta1*l)+c3*cosh(beta1*l)+c4*sinh(beta1*l))+m*a2*beta1^4*(c1*sin(beta1*l)+c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0], ...
                        [beta1,c1,c2,c3,c4],[(n-1)*pi/l,(n)*pi/l;-1,1;-1,1;-1,1;-1,1]);
                    beta=double(beta1);
                    c1=double(c1);
                    c2=double(c2);
                    c3=double(c3);
                    c4=double(c4);
                    % output
                    omega=sqrt(beta^4*E*I/(rho*A));
                    app.radsEditField_9.Value=omega;
                    beam_x = linspace(0,l,500); % 主振型横坐标离散
                    beam_y = c1.*sin(beta.*beam_x)+c2.*cos(beta.*beam_x)+c3.*sinh(beta.*beam_x)+c4.*cosh(beta.*beam_x);  
                    beam_y = flip(beam_y);
                    plot(app.UIAxes2_3,beam_x,beam_y); 
                case "自由端"
                    if n == 1 
                        plot(app.UIAxes2_3,[0 l],[1 1]);
                    elseif n == 2
                        plot(app.UIAxes2_3,[0 l],[-1 1]);
                    else
                        m=app.EditField_7.Value;
                        syms beta1 c1 c2 c3 c4
                        [beta1,c1,c2,c3,c4] = vpasolve([c1^2+c2^2+c3^2+c4^2==1, ...
                            E*I*beta1^2*(-c1*sin(beta1*0)-c2*cos(beta1*0)+c3*sinh(beta1*0)+c4*cosh(beta1*0))==0, ...
                            E*I*beta1^3*(-c1*cos(beta1*0)+c2*sin(beta1*0)+c3*cosh(beta1*0)+c4*sinh(beta1*0))==0, ...
                            E*I*beta1^2*(-c1*sin(beta1*l)-c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0 ...
                            E*I*beta1^3*(-c1*cos(beta1*l)+c2*sin(beta1*l)+c3*cosh(beta1*l)+c4*sinh(beta1*l))+m*a2*beta1^4*(c1*sin(beta1*l)+c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0], ...
                            [beta1,c1,c2,c3,c4],[(n-2)*pi/l,(n-1)*pi/l;-1,1;-1,1;-1,1;-1,1]);
                        beta=double(beta1);
                        c1=double(c1);
                        c2=double(c2);
                        c3=double(c3);
                        c4=double(c4); 
                        % output
                        omega=sqrt(beta^4*E*I/(rho*A));
                        app.radsEditField_9.Value=omega;
                        beam_x = linspace(0,l,500); % 主振型横坐标离散
                        beam_y = c1.*sin(beta.*beam_x)+c2.*cos(beta.*beam_x)+c3.*sinh(beta.*beam_x)+c4.*cosh(beta.*beam_x);  
                        beam_y = flip(beam_y);
                        plot(app.UIAxes2_3,beam_x,beam_y); 
                    end
                case "铰支端"
                    if n == 1
                        plot(app.UIAxes2_3,[0 l],[1 0]);
                    else
                        m=app.EditField_7.Value;
                        syms beta1 c1 c2 c3 c4
                        [beta1,c1,c2,c3,c4] = vpasolve([c1^2+c2^2+c3^2+c4^2==1, ...
                            c1*sin(beta1*0)+c2*cos(beta1*0)+c3*sinh(beta1*0)+c4*cosh(beta1*0)==0,...
                            E*I*beta1^2*(-c1*sin(beta1*0)-c2*cos(beta1*0)+c3*sinh(beta1*0)+c4*cosh(beta1*0))==0, ...
                            E*I*beta1^2*(-c1*sin(beta1*l)-c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0 ...
                            E*I*beta1^3*(-c1*cos(beta1*l)+c2*sin(beta1*l)+c3*cosh(beta1*l)+c4*sinh(beta1*l))+m*a2*beta1^4*(c1*sin(beta1*l)+c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0], ...
                            [beta1,c1,c2,c3,c4],[(n-1.5)*pi/l,(n-0.5)*pi/l;-1,1;-1,1;-1,1;-1,1]);
                        beta=double(beta1);
                        c1=double(c1);
                        c2=double(c2);
                        c3=double(c3);
                        c4=double(c4);
                        % output
                        omega=sqrt(beta^4*E*I/(rho*A));
                        app.radsEditField_9.Value=omega;
                        beam_x = linspace(0,l,500); % 主振型横坐标离散
                        beam_y = c1.*sin(beta.*beam_x)+c2.*cos(beta.*beam_x)+c3.*sinh(beta.*beam_x)+c4.*cosh(beta.*beam_x);  
                        beam_y = flip(beam_y);
                        plot(app.UIAxes2_3,beam_x,beam_y); 
                    end
                case "弹性约束"
                    k_l=app.EditField_6.Value;
                    kt_l=app.EditField_9.Value;
                    m=app.EditField_7.Value;
                    syms beta1 c1 c2 c3 c4
                    [beta1,c1,c2,c3,c4] = vpasolve([c1^2+c2^2+c3^2+c4^2==1, ...
                        E*I*beta1^2*(-c1*sin(beta1*0)-c2*cos(beta1*0)+c3*sinh(beta1*0)+c4*cosh(beta1*0))-kt_l*beta1*(c1*cos(beta1*0)-c2*sin(beta1*0)+c3*cosh(beta1*0)+c4*sinh(beta1*0))==0 ...
                        E*I*beta1^3*(-c1*cos(beta1*0)+c2*sin(beta1*0)+c3*cosh(beta1*0)+c4*sinh(beta1*0))+k_l*(c1*sin(beta1*0)+c2*cos(beta1*0)+c3*sinh(beta1*0)+c4*cosh(beta1*0))==0, ...
                        E*I*beta1^2*(-c1*sin(beta1*l)-c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0 ...
                        E*I*beta1^3*(-c1*cos(beta1*l)+c2*sin(beta1*l)+c3*cosh(beta1*l)+c4*sinh(beta1*l))+m*a2*beta1^4*(c1*sin(beta1*l)+c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0], ...
                        [beta1,c1,c2,c3,c4],[(n-2)*pi/l,(n-1)*pi/l;-1,1;-1,1;-1,1;-1,1]);
                    beta=double(beta1);
                    c1=double(c1);
                    c2=double(c2);
                    c3=double(c3);
                    c4=double(c4);
                    % output
                    omega=sqrt(beta^4*E*I/(rho*A));
                    app.radsEditField_9.Value=omega;
                    beam_x = linspace(0,l,500); % 主振型横坐标离散
                    beam_y = c1.*sin(beta.*beam_x)+c2.*cos(beta.*beam_x)+c3.*sinh(beta.*beam_x)+c4.*cosh(beta.*beam_x);  
                    beam_y = flip(beam_y);
                    plot(app.UIAxes2_3,beam_x,beam_y); 
                case "惯性约束"
                    if n == 1 
                        plot(app.UIAxes2_3,[0 l],[1 1]);
                    elseif n == 2
                        plot(app.UIAxes2_3,[0 l],[-1 1]);
                    else
                        m_l=app.EditField_7.Value;
                        m_r=app.EditField_6.Value;
                        syms beta1 c1 c2 c3 c4
                        [beta1,c1,c2,c3,c4] = vpasolve([c1^2+c2^2+c3^2+c4^2==1, ...
                            E*I*beta1^2*(-c1*sin(beta1*0)-c2*cos(beta1*0)+c3*sinh(beta1*0)+c4*cosh(beta1*0))==0 ...
                            E*I*beta1^3*(-c1*cos(beta1*0)+c2*sin(beta1*0)+c3*cosh(beta1*0)+c4*sinh(beta1*0))-m_l*a2*beta1^4*(c1*sin(beta1*0)+c2*cos(beta1*0)+c3*sinh(beta1*0)+c4*cosh(beta1*0))==0, ...
                            E*I*beta1^2*(-c1*sin(beta1*l)-c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0 ...
                            E*I*beta1^3*(-c1*cos(beta1*l)+c2*sin(beta1*l)+c3*cosh(beta1*l)+c4*sinh(beta1*l))+m_r*a2*beta1^4*(c1*sin(beta1*l)+c2*cos(beta1*l)+c3*sinh(beta1*l)+c4*cosh(beta1*l))==0], ...
                            [beta1,c1,c2,c3,c4],[(n-2)*pi/l,(n-1)*pi/l;-1,1;-1,1;-1,1;-1,1]);
                        beta=double(beta1);
                        c1=double(c1);
                        c2=double(c2);
                        c3=double(c3);
                        c4=double(c4);
                        % output
                        omega=sqrt(beta^4*E*I/(rho*A));
                        app.radsEditField_9.Value=omega;
                        beam_x = linspace(0,l,500); % 主振型横坐标离散
                        beam_y = c1.*sin(beta.*beam_x)+c2.*cos(beta.*beam_x)+c3.*sinh(beta.*beam_x)+c4.*cosh(beta.*beam_x);  
                        plot(app.UIAxes2_3,beam_x,beam_y); 
                    end
            end    
        end
        end

        % Button pushed function: CalculateButton_5
        function CalculateButton_5Pushed(app, event)
            n=app.Spinner_5.Value; %系统自由度数，范围1~5；
            %% create array
            m=zeros(1,5);
            k=zeros(1,5);
            x=zeros(1,5);
            v=zeros(1,5);
            %% import
            m(1)=app.m1kgEditField.Value;
            m(2)=app.m2kgEditField.Value;
            m(3)=app.m3kgEditField.Value;
            m(4)=app.m4kgEditField.Value;
            m(5)=app.m5kgEditField.Value;
            k(1)=app.k1NmEditField.Value;
            k(2)=app.k2NmEditField.Value;
            k(3)=app.k3NmEditField.Value;
            k(4)=app.k3NmEditField_2.Value;
            k(5)=app.k4NmEditField.Value;
            x(1)=app.x1mEditField.Value;
            x(2)=app.x2mEditField.Value;
            x(3)=app.x3mEditField.Value;
            x(4)=app.x4mEditField.Value;
            x(5)=app.x5mEditField.Value;
            v(1)=app.v1msEditField.Value;
            v(2)=app.v2msEditField.Value;
            v(3)=app.v3msEditField.Value;
            v(4)=app.v4msEditField.Value;
            v(5)=app.v5msEditField.Value;
            %% calculate
            syms omega2 %定义频率的平方为参数omega2，这样保证解出正根，最后再开方
            z=zeros(n); %定义频率方程矩阵
            for p = 1:n %行
                for q = 1:n %列
                    if p == q
                        %幅值主对角线元素
                        if p<n %非最后一个元素
                            z(p,q) = k(p)+k(p+1);
                        else %最后一个元素
                            z(p,q) = k(n);
                        end
                    elseif (q-p)==1
                        %赋值主对角线上方元素
                        z(p,q) = -1*k(q);
                    elseif (p-q)==1
                        %赋值主对角线下方元素
                        z(p,q) = -1*k(q);
                    end
                end
            end
            M=zeros(1,n);
            for p = 1:n
                M(p)=m(p);
            end
            z=z-omega2*diag(M);
            omega_result2=solve(det(z));
            %% output
            %output_omega
            omega_output=zeros(1,5);
            for p = 1:n
                omega_output(p)=sqrt(omega_result2(p));
            end
            for p = 1:5
                if omega_output(p) == 0
                    omega_output(p)=max(omega_output)*2;
                end
            end
            omega_output=sort(omega_output);%对各阶频率从小到大排序
            app.radsEditField_10.Value=omega_output(1);
            app.radsEditField_13.Value=omega_output(2);
            app.radsEditField_12.Value=omega_output(3);
            app.radsEditField_11.Value=omega_output(4);
            app.radsEditField_14.Value=omega_output(5);
            
            %固有振型图绘制
            omega_m=app.Spinner_4.Value; %第m阶振动频率对应的固有振型
            %代入计算得到的第m阶园频率，得到对应矩阵
            z_result=zeros(n); %定义频率方程矩阵
            for p = 1:n %行
                for q = 1:n %列
                    if p == q
                        %幅值主对角线元素
                        if p<n %非最后一个元素
                            z_result(p,q) = k(p)+k(p+1);
                        else %最后一个元素
                            z_result(p,q) = k(n);
                        end
                    elseif (q-p)==1
                        %赋值主对角线上方元素
                        z_result(p,q) = -1*k(q);
                    elseif (p-q)==1
                        %赋值主对角线下方元素
                        z_result(p,q) = -1*k(q);
                    end
                end
            end
            v1=zeros(1,n);
            for p = 1:n
                v1(p)=m(p);
            end
            z_result=z_result-omega_output(omega_m)^2*diag(v1);
            %计算固有振型
            output_x=linspace(0,1,n+1);
            output_y=zeros(1,n+1);
            r=rank(z_result);
            y=null(z_result, 'r');
            [p_y,q_y]=size(y);
            for q = 1:q_y
                if y(p_y,q)==1 || y(p_y,q)== -1
                    for u=1:p_y
                        output_y(u+1)=y(u,q);
                    end
                    break
                end
            end
            %单独讨论单自由度的固有振型
            if n == 1
                output_y(2)=1;
            end
%             for u=1:p_y
%                 output_y(u+1)=y(u,1);
%             end
            plot(app.UIAxes6,output_x,output_y,'linewidth',1.5);
            

        end

        % Value changing function: Spinner_5
        function Spinner_5ValueChanging(app, event)
            changingValue = event.Value;
            switch changingValue
                case 1
                    app.Image14.Visible='on';
                    app.Image15.Visible='off';
                    app.Image16.Visible='off';
                    app.Image17.Visible='off';

                    app.Image18.Visible='on';
                    app.Image18_2.Visible='on';
                    app.Image18_3.Visible='on';
                    app.Image18_4.Visible='on';

                    app.Image13.ImageSource='Multi-DoF-5.jpg';

                    app.Spinner_4.Value=1;
                    app.Spinner_4.Enable ="off";
                    app.Spinner_6.Value=1;
                    app.Spinner_6.Enable ="off";
                case 2
                    app.Image14.Visible='off';
                    app.Image15.Visible='on';
                    app.Image16.Visible='off';
                    app.Image17.Visible='off';

                    app.Image18.Visible='off';
                    app.Image18_2.Visible='on';
                    app.Image18_3.Visible='on';
                    app.Image18_4.Visible='on';

                    app.Image13.ImageSource='Multi-DoF-4.jpg';

                    app.Spinner_4.Limits=[1 2];
                    app.Spinner_4.Enable="on";
                    app.Spinner_6.Limits=[1 2];
                    app.Spinner_6.Enable="on";
                case 3
                    app.Image14.Visible='off';
                    app.Image15.Visible='off';
                    app.Image16.Visible='on';
                    app.Image17.Visible='off';

                    app.Image18.Visible='off';
                    app.Image18_2.Visible='off';
                    app.Image18_3.Visible='on';
                    app.Image18_4.Visible='on';

                    app.Image13.ImageSource='Multi-DoF-3.jpg';

                    app.Spinner_4.Limits=[1 3];
                    app.Spinner_4.Enable="on";
                    app.Spinner_6.Limits=[1 3];
                    app.Spinner_6.Enable="on";
                case 4
                    app.Image14.Visible='off';
                    app.Image15.Visible='off';
                    app.Image16.Visible='off';
                    app.Image17.Visible='on';

                    app.Image18.Visible='off';
                    app.Image18_2.Visible='off';
                    app.Image18_3.Visible='off';
                    app.Image18_4.Visible='on';

                    app.Image13.ImageSource='Multi-DoF-2.jpg';

                    app.Spinner_4.Limits=[1 4];
                    app.Spinner_4.Enable="on";
                    app.Spinner_6.Limits=[1 4];
                    app.Spinner_6.Enable="on";
                case 5
                    app.Image14.Visible='off';
                    app.Image15.Visible='off';
                    app.Image16.Visible='off';
                    app.Image17.Visible='off';

                    app.Image18.Visible='off';
                    app.Image18_2.Visible='off';
                    app.Image18_3.Visible='off';
                    app.Image18_4.Visible='off';

                    app.Image13.ImageSource='Multi-DoF.jpg';

                    app.Spinner_4.Limits=[1 5];
                    app.Spinner_4.Enable="on";
                    app.Spinner_6.Limits=[1 5];
                    app.Spinner_6.Enable="on";
            end    
        end

        % Button pushed function: CalculateButton_6
        function CalculateButton_6Pushed(app, event)
            %imput
            n=app.Spinner_5.Value; %系统自由度数，范围1~5；
            %% create array
            m=zeros(1,5);
            k=zeros(1,5);
            x=zeros(1,5);
            v=zeros(1,5);
            %% import
            m(1)=app.m1kgEditField.Value;
            m(2)=app.m2kgEditField.Value;
            m(3)=app.m3kgEditField.Value;
            m(4)=app.m4kgEditField.Value;
            m(5)=app.m5kgEditField.Value;
            k(1)=app.k1NmEditField.Value;
            k(2)=app.k2NmEditField.Value;
            k(3)=app.k3NmEditField.Value;
            k(4)=app.k3NmEditField_2.Value;
            k(5)=app.k4NmEditField.Value;
            x(1)=app.x1mEditField.Value;
            x(2)=app.x2mEditField.Value;
            x(3)=app.x3mEditField.Value;
            x(4)=app.x4mEditField.Value;
            x(5)=app.x5mEditField.Value;
            v(1)=app.v1msEditField.Value;
            v(2)=app.v2msEditField.Value;
            v(3)=app.v3msEditField.Value;
            v(4)=app.v4msEditField.Value;
            v(5)=app.v5msEditField.Value;
            %% 绘制自由振动曲线
            %(copy from:https://github.com/John70333/MDOF.git)
            %数据读入(n,m,k,x,v,t,delta_t,ShowWhich,ShowWhat)
            % pre_def
            m1 = zeros(1,n);
            x0 = zeros(n,1);
            v0 = zeros(n,1);
            for p = 1:n
                m1(p)=m(p);
                x0(p)=x(p);
                v0(p)=v(p);
            end
            J = diag(m1);%质量矩阵
            C = zeros(n);%阻尼矩阵
            K = zeros(n);%刚度矩阵(初始化）
            for p = 1:n %行
                for q = 1:n %列
                    if p == q
                        %幅值主对角线元素
                        if p<n %非最后一个元素
                            K(p,q) = k(p)+k(p+1);
                        else %最后一个元素
                            K(p,q) = k(n);
                        end
                    elseif (q-p)==1
                        %赋值主对角线上方元素
                        K(p,q) = -1*k(q);
                    elseif (p-q)==1
                        %赋值主对角线下方元素
                        K(p,q) = -1*k(q);
                    end
                end
            end
            f = @(t) (zeros(n,1)).*sin(160*pi*t); p = [1,1/80,10];%激励  %%%%%这里还没改，换成零激励
            ti = linspace(0,app.sEditField_2.Value,(app.sEditField_2.Value*app.HzEditField_2.Value)+1);%求解时间
            % calculate
            %draw是定义的求解函数
            [l1,u1,xt1,vt1,M1] = draw(n,J,zeros(n),K,x0,v0,@(t)zeros(n,1),p,ti);%无阻尼，自由！！！
            % plot
            choose=app.Spinner_6.Value;
            switch app.DropDown_6.Value
                case '位移'
                    app.UIAxes7.Title.String='多自由度系统：指定振动对象自由振动曲线——位移(m)';
                    plot(app.UIAxes7,ti,xt1(choose,:),'linewidth',1.5);
                case '速度'
                    app.UIAxes7.Title.String='多自由度系统：指定振动对象自由振动曲线——速度(m/s)';
                    plot(app.UIAxes7,ti,vt1(choose,:),'linewidth',1.5);
                case '加速度'
                    app.UIAxes7.Title.String='多自由度系统：指定振动对象自由振动曲线——加速度(m/s^2)';
                    at=diff(vt1(choose,:))*app.HzEditField_2.Value;
                    plot(app.UIAxes7,ti(1:length(ti)-1),at,'linewidth',1.5);
            end
            %
            function [l,u,xt,vt,M] = draw(n,J,C,K,x0,v0,f,p,ti)
            A = [C,J;J,zeros(n)]; B = [K,zeros(n);zeros(n),-J]; M = {};
            y0 = [x0;v0]; q = @(t)[f(t);zeros(n,1)];
            [U,l] = eig(-A\B,'v'); u = U(1:n,:)./U(1,:);
            z0 = U.'*A*y0; a = diag(U.'*A*U);
            if isequal(C*(J\K),K*(J\C))
                rd = @(ded,der)real(diag(der.'*ded*der));
                u = real(u(:,1:2:end)); M = {rd(J,u),rd(C,u),rd(K,u)};
            end
            if p(1) == 1
                [a0,an,bn,wn] = fss(@(t)U.'*q(t),0,p(2),2*n,p(3));
                g = @(x)-a0./l;
                ai = (-an.*l-bn.*wn)./(l.^2+wn.^2);
                bi = (-bn.*l+an.*wn)./(l.^2+wn.^2);
                for i = 1:p(3)
                    g = @(x)g(x)+ai(:,i).*cos(wn(i).*x)+bi(:,i).*sin(wn(i).*x);
                end
                g = @(x)g(x).*exp(-l.*x)-g(0);
            elseif p(1) == 0
                g = @(t)integral(@(tj)U.'*q(tj).*exp(-l*tj),0,t,'arrayvalued',true);
            end
            y = @(t) U*((z0+g(t)).*exp(l*t)./a); yt = zeros(2*n,length(ti));
            for i = 1:length(ti)
                yt(:,i) = y(ti(i));
            end
            xt = yt(1:n,:); vt = yt(n+1:2*n,:);
            end
            function [a0,a,b,w] = fss(f,l,u,nf,nl)
            a0 = 1/(u-l)*integral(f,l,u,'arrayvalued',true);
            a = zeros(nf,nl); b = zeros(nf,nl); w = zeros(1,nl);
            for p = 1:nl
                w(p) = 2*p*pi/(u-l);
                a(:,p) = 2/(u-l)*integral(@(x)f(x).*cos(w(p)*x),l,u,'arrayvalued',true);
                b(:,p) = 2/(u-l)*integral(@(x)f(x).*sin(w(p)*x),l,u,'arrayvalued',true);
            end
            end
        end

        % Value changed function: DropDown_5
        function DropDown_5ValueChanged(app, event)
            value = app.DropDown_5.Value;
            switch value
                case '固定端'
                    app.EditField_7.Visible='off';
                    app.EditField_8.Visible='off';
                    app.kgLabel_4.Visible='off';
                    app.NmLabel_4.Visible='off';
                    app.NmradLabel_3.Visible='off';
                    app.Image19.ImageSource='梁-固定端-左.jpg';
                case '自由端'
                    app.EditField_7.Visible='off';
                    app.EditField_8.Visible='off';
                    app.kgLabel_4.Visible='off';
                    app.NmLabel_4.Visible='off';
                    app.NmradLabel_3.Visible='off';
                    app.Image19.ImageSource='梁-自由端-左.jpg';
                case '铰支端'
                    app.EditField_7.Visible='off';
                    app.EditField_8.Visible='off';
                    app.kgLabel_4.Visible='off';
                    app.NmLabel_4.Visible='off';
                    app.NmradLabel_3.Visible='off';
                    app.Image19.ImageSource='梁-铰支端-左.jpg';
                case '弹性约束'
                    app.EditField_7.Visible='on';
                    app.EditField_8.Visible='on';
                    app.kgLabel_4.Visible='off';
                    app.NmLabel_4.Visible='on';
                    app.NmradLabel_3.Visible='on';
                    app.Image19.ImageSource='梁-弹性约束-左.jpg';
                case '惯性约束'
                    app.EditField_7.Visible='on';
                    app.EditField_8.Visible='off';
                    app.kgLabel_4.Visible='on';
                    app.NmLabel_4.Visible='off';
                    app.NmradLabel_3.Visible='off';
                     app.Image19.ImageSource='梁-惯性约束-左.jpg';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleDoFTab = uitab(app.TabGroup);
            app.SingleDoFTab.Title = 'Single-DoF';
            app.UIAxes = uiaxes(app.SingleDoFTab);
            title(app.UIAxes, '位移x')
            xlabel(app.UIAxes, '时间(s)')
            ylabel(app.UIAxes, '(m)')
            app.UIAxes_2 = uiaxes(app.SingleDoFTab);
            title(app.UIAxes_2, '速度v')
            xlabel(app.UIAxes_2, '时间(s)')
            ylabel(app.UIAxes_2, '(m/s)')
            app.UIAxes_3 = uiaxes(app.SingleDoFTab);
            title(app.UIAxes_3, '加速度a')
            xlabel(app.UIAxes_3, '时间(s)')
            ylabel(app.UIAxes_3, '(m/s^2)')
            app.Label = uilabel(app.SingleDoFTab);
            app.Label.Text = {'1、单自由度振动模型如右图所示；'; '2、输入参数：质量、弹簧刚度系数、'; '阻尼系数、初始条件、求解时间设置；'; '3、若存在外激励，请输入正弦激励的'; '幅值与圆频率；'; '注意：各物理量均为国际单位，正'; '方向定义与右图保持一致。'; ''};
            app.kgEditFieldLabel = uilabel(app.SingleDoFTab);
            app.kgEditFieldLabel.Text = '质量(kg)';
            app.kgEditField = uieditfield(app.SingleDoFTab, 'numeric');
            app.kgEditField.LowerLimitInclusive = 'off';
            app.kgEditField.Limits = [0 Inf];
            app.kgEditField.Value = 1;
            app.NmEditFieldLabel = uilabel(app.SingleDoFTab);
            app.NmEditFieldLabel.Text = '刚度系数(N/m)';
            app.NmEditField = uieditfield(app.SingleDoFTab, 'numeric');
            app.NmEditField.LowerLimitInclusive = 'off';
            app.NmEditField.Limits = [0 Inf];
            app.NmEditField.Value = 2;
            app.NsmEditFieldLabel = uilabel(app.SingleDoFTab);
            app.NsmEditFieldLabel.Text = '阻尼系数(N*s/m)';
            app.NsmEditField = uieditfield(app.SingleDoFTab, 'numeric');
            app.NsmEditField.Limits = [0 Inf];
            app.NsmEditField.Value = 0.3;
            app.Label_2 = uilabel(app.SingleDoFTab);
            app.Label_2.Text = '单自由度振动计算';
            app.Label_3 = uilabel(app.SingleDoFTab);
            app.Label_3.Text = '参数输入';
            app.ANLabel = uilabel(app.SingleDoFTab);
            app.ANLabel.Text = '幅值A(N)';
            app.ANEditField = uieditfield(app.SingleDoFTab, 'numeric');
            app.ANEditField.Value = 0.3;
            app.radsLabel = uilabel(app.SingleDoFTab);
            app.radsLabel.Text = '圆频率ω(rad/s)';
            app.radsEditField = uieditfield(app.SingleDoFTab, 'numeric');
            app.radsEditField.LowerLimitInclusive = 'off';
            app.radsEditField.Value = 3;
            app.CalculateButton = uibutton(app.SingleDoFTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Label_5 = uilabel(app.SingleDoFTab);
            app.Label_5.Text = '计算结果';
            app.radsEditField_2Label = uilabel(app.SingleDoFTab);
            app.radsEditField_2Label.Text = '固有圆频率(rad/s)';
            app.radsEditField_2 = uieditfield(app.SingleDoFTab, 'numeric');
            app.radsEditField_2.Editable = 'off';
            app.radsEditField_3Label = uilabel(app.SingleDoFTab);
            app.radsEditField_3Label.Text = '阻尼振动圆频率(rad/s)';
            app.radsEditField_3 = uieditfield(app.SingleDoFTab, 'numeric');
            app.radsEditField_3.Editable = 'off';
            app.radsEditField_4Label = uilabel(app.SingleDoFTab);
            app.radsEditField_4Label.Text = '位移共振圆频率(rad/s)';
            app.radsEditField_4 = uieditfield(app.SingleDoFTab, 'numeric');
            app.radsEditField_4.Editable = 'off';
            app.radsEditField_5Label = uilabel(app.SingleDoFTab);
            app.radsEditField_5Label.Text = '速度共振圆频率(rad/s)';
            app.radsEditField_5 = uieditfield(app.SingleDoFTab, 'numeric');
            app.radsEditField_5.Editable = 'off';
            app.radsEditField_6Label = uilabel(app.SingleDoFTab);
            app.radsEditField_6Label.Text = '加速度共振圆频率(rad/s)';
            app.radsEditField_6 = uieditfield(app.SingleDoFTab, 'numeric');
            app.radsEditField_6.Editable = 'off';
            app.sEditFieldLabel = uilabel(app.SingleDoFTab);
            app.sEditFieldLabel.Text = '计算时间(s)';
            app.sEditField = uieditfield(app.SingleDoFTab, 'numeric');
            app.sEditField.LowerLimitInclusive = 'off';
            app.sEditField.Limits = [0 Inf];
            app.sEditField.Value = 30;
            app.FtAsintLabel = uilabel(app.SingleDoFTab);
            app.FtAsintLabel.Text = '外激励形式:F(t)=A*sin(ωt)';
            app.mEditFieldLabel = uilabel(app.SingleDoFTab);
            app.mEditFieldLabel.Text = '初始位移(m)';
            app.mEditField = uieditfield(app.SingleDoFTab, 'numeric');
            app.mEditField.Value = 1;
            app.msEditFieldLabel = uilabel(app.SingleDoFTab);
            app.msEditFieldLabel.Text = '初始速度(m/s)';
            app.msEditField = uieditfield(app.SingleDoFTab, 'numeric');
            app.HzEditFieldLabel = uilabel(app.SingleDoFTab);
            app.HzEditFieldLabel.Text = '采样频率(Hz)';
            app.HzEditField = uieditfield(app.SingleDoFTab, 'numeric');
            app.HzEditField.LowerLimitInclusive = 'off';
            app.HzEditField.Limits = [0 Inf];
            app.HzEditField.Value = 10;
            app.Image3 = uiimage(app.SingleDoFTab);
            app.Image3.ImageSource = 'Single-DoF.jpg';
            app.MultiDoFTab = uitab(app.TabGroup);
            app.MultiDoFTab.Title = 'Multi-DoF';
            app.UIAxes6 = uiaxes(app.MultiDoFTab);
            title(app.UIAxes6, '指定阶数固有振型')
            zlabel(app.UIAxes6, 'Z')
            colormap(app.UIAxes6, 'white')
            app.UIAxes7 = uiaxes(app.MultiDoFTab);
            title(app.UIAxes7, '多自由度系统：指定振动对象自由振动曲线——位移(m)')
            xlabel(app.UIAxes7, '时间(s)')
            zlabel(app.UIAxes7, 'Z')
            app.CalculateButton_5 = uibutton(app.MultiDoFTab, 'push');
            app.CalculateButton_5.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_5Pushed, true);
            app.CalculateButton_5.Text = 'Calculate';
            app.Image13 = uiimage(app.MultiDoFTab);
            app.Image13.ImageSource = fullfile(pathToMLAPP, 'Multi-DoF-5.jpg');
            app.Label_25 = uilabel(app.MultiDoFTab);
            app.Label_25.Text = {'1、计算1~5自由度情况下无阻尼自由振动；'; '2、输入：系统自由度、质量、弹簧刚度、初始条件，输出：各阶'; '固有频率及对应固有振型，系统运动动画；'};
            app.Label_26 = uilabel(app.MultiDoFTab);
            app.Label_26.Text = '多自由度振动计算';
            app.m1kgEditFieldLabel = uilabel(app.MultiDoFTab);
            app.m1kgEditFieldLabel.Text = 'm1(kg)';
            app.m1kgEditField = uieditfield(app.MultiDoFTab, 'numeric');
            app.m1kgEditField.LowerLimitInclusive = 'off';
            app.m1kgEditField.Limits = [0 Inf];
            app.m1kgEditField.Value = 1;
            app.m2kgEditFieldLabel = uilabel(app.MultiDoFTab);
            app.m2kgEditFieldLabel.Text = 'm2(kg)';
            app.m2kgEditField = uieditfield(app.MultiDoFTab, 'numeric');
            app.m2kgEditField.LowerLimitInclusive = 'off';
            app.m2kgEditField.Limits = [0 Inf];
            app.m2kgEditField.Value = 1;
            app.m3kgEditFieldLabel = uilabel(app.MultiDoFTab);
            app.m3kgEditFieldLabel.Text = 'm3(kg)';
            app.m3kgEditField = uieditfield(app.MultiDoFTab, 'numeric');
            app.m3kgEditField.LowerLimitInclusive = 'off';
            app.m3kgEditField.Limits = [0 Inf];
            app.m3kgEditField.Value = 1;
            app.m4kgEditFieldLabel = uilabel(app.MultiDoFTab);
            app.m4kgEditFieldLabel.Text = 'm4(kg)';
            app.m4kgEditField = uieditfield(app.MultiDoFTab, 'numeric');
            app.m4kgEditField.LowerLimitInclusive = 'off';
            app.m4kgEditField.Limits = [0 Inf];
            app.m4kgEditField.Value = 1;
            app.m5kgEditFieldLabel = uilabel(app.MultiDoFTab);
            app.m5kgEditFieldLabel.Text = 'm5(kg)';
            app.m5kgEditField = uieditfield(app.MultiDoFTab, 'numeric');
            app.m5kgEditField.LowerLimitInclusive = 'off';
            app.m5kgEditField.Limits = [0 Inf];
            app.m5kgEditField.Value = 2;
            app.k1NmEditFieldLabel = uilabel(app.MultiDoFTab);
            app.k1NmEditFieldLabel.Text = 'k1(N/m)';
            app.k1NmEditField = uieditfield(app.MultiDoFTab, 'numeric');
            app.k1NmEditField.LowerLimitInclusive = 'off';
            app.k1NmEditField.Limits = [0 Inf];
            app.k1NmEditField.Value = 0.01;
            app.k2NmEditFieldLabel = uilabel(app.MultiDoFTab);
            app.k2NmEditFieldLabel.Text = 'k2(N/m)';
            app.k2NmEditField = uieditfield(app.MultiDoFTab, 'numeric');
            app.k2NmEditField.LowerLimitInclusive = 'off';
            app.k2NmEditField.Limits = [0 Inf];
            app.k2NmEditField.Value = 0.01;
            app.k3NmEditFieldLabel = uilabel(app.MultiDoFTab);
            app.k3NmEditFieldLabel.Text = 'k3(N/m)';
            app.k3NmEditField = uieditfield(app.MultiDoFTab, 'numeric');
            app.k3NmEditField.LowerLimitInclusive = 'off';
            app.k3NmEditField.Limits = [0 Inf];
            app.k3NmEditField.Value = 0.01;
            app.k3NmEditField_2Label = uilabel(app.MultiDoFTab);
            app.k3NmEditField_2Label.Text = 'k3(N/m)';
            app.k3NmEditField_2 = uieditfield(app.MultiDoFTab, 'numeric');
            app.k3NmEditField_2.LowerLimitInclusive = 'off';
            app.k3NmEditField_2.Limits = [0 Inf];
            app.k3NmEditField_2.Value = 0.01;
            app.k4NmEditFieldLabel = uilabel(app.MultiDoFTab);
            app.k4NmEditFieldLabel.Text = 'k4(N/m)';
            app.k4NmEditField = uieditfield(app.MultiDoFTab, 'numeric');
            app.k4NmEditField.LowerLimitInclusive = 'off';
            app.k4NmEditField.Limits = [0 Inf];
            app.k4NmEditField.Value = 0.01;
            app.x1mEditFieldLabel = uilabel(app.MultiDoFTab);
            app.x1mEditFieldLabel.Text = 'x1(m)';
            app.x1mEditField = uieditfield(app.MultiDoFTab, 'numeric');
            app.x2mEditFieldLabel = uilabel(app.MultiDoFTab);
            app.x2mEditFieldLabel.Text = 'x2(m)';
            app.x2mEditField = uieditfield(app.MultiDoFTab, 'numeric');
            app.x3mEditFieldLabel = uilabel(app.MultiDoFTab);
            app.x3mEditFieldLabel.Text = 'x3(m)';
            app.x3mEditField = uieditfield(app.MultiDoFTab, 'numeric');
            app.x4mEditFieldLabel = uilabel(app.MultiDoFTab);
            app.x4mEditFieldLabel.Text = 'x4(m)';
            app.x4mEditField = uieditfield(app.MultiDoFTab, 'numeric');
            app.x5mEditFieldLabel = uilabel(app.MultiDoFTab);
            app.x5mEditFieldLabel.Text = 'x5(m)';
            app.x5mEditField = uieditfield(app.MultiDoFTab, 'numeric');
            app.v1msEditFieldLabel = uilabel(app.MultiDoFTab);
            app.v1msEditFieldLabel.Text = 'v1(m/s)';
            app.v1msEditField = uieditfield(app.MultiDoFTab, 'numeric');
            app.v2msEditFieldLabel = uilabel(app.MultiDoFTab);
            app.v2msEditFieldLabel.Text = 'v2(m/s)';
            app.v2msEditField = uieditfield(app.MultiDoFTab, 'numeric');
            app.v3msEditFieldLabel = uilabel(app.MultiDoFTab);
            app.v3msEditFieldLabel.Text = 'v3(m/s)';
            app.v3msEditField = uieditfield(app.MultiDoFTab, 'numeric');
            app.v4msEditFieldLabel = uilabel(app.MultiDoFTab);
            app.v4msEditFieldLabel.Text = 'v4(m/s)';
            app.v4msEditField = uieditfield(app.MultiDoFTab, 'numeric');
            app.v5msEditFieldLabel = uilabel(app.MultiDoFTab);
            app.v5msEditFieldLabel.Text = 'v5(m/s)';
            app.v5msEditField = uieditfield(app.MultiDoFTab, 'numeric');
            app.radsEditField_10Label = uilabel(app.MultiDoFTab);
            app.radsEditField_10Label.Text = '1阶固有圆频率(rad/s)';
            app.radsEditField_10 = uieditfield(app.MultiDoFTab, 'numeric');
            app.radsEditField_10.Editable = 'off';
            app.Label_27 = uilabel(app.MultiDoFTab);
            app.Label_27.Text = '计算结果';
            app.radsLabel_4 = uilabel(app.MultiDoFTab);
            app.radsLabel_4.Text = '4阶固有圆频率(rad/s)';
            app.radsEditField_11 = uieditfield(app.MultiDoFTab, 'numeric');
            app.radsEditField_11.Editable = 'off';
            app.radsLabel_3 = uilabel(app.MultiDoFTab);
            app.radsLabel_3.Text = '3阶固有圆频率(rad/s)';
            app.radsEditField_12 = uieditfield(app.MultiDoFTab, 'numeric');
            app.radsEditField_12.Editable = 'off';
            app.radsLabel_2 = uilabel(app.MultiDoFTab);
            app.radsLabel_2.Text = '2阶固有圆频率(rad/s)';
            app.radsEditField_13 = uieditfield(app.MultiDoFTab, 'numeric');
            app.radsEditField_13.Editable = 'off';
            app.radsLabel_5 = uilabel(app.MultiDoFTab);
            app.radsLabel_5.Text = '5阶固有圆频率(rad/s)';
            app.radsEditField_14 = uieditfield(app.MultiDoFTab, 'numeric');
            app.radsEditField_14.Editable = 'off';
            app.Label_28 = uilabel(app.MultiDoFTab);
            app.Label_28.Text = '参数输入';
            app.Label_29 = uilabel(app.MultiDoFTab);
            app.Label_29.Text = '固有振型阶数';
            app.Spinner_4 = uispinner(app.MultiDoFTab);
            app.Spinner_4.Limits = [1 5];
            app.Spinner_4.RoundFractionalValues = 'on';
            app.Spinner_4.Enable = 'off';
            app.Spinner_4.Value = 1;
            app.Label_30 = uilabel(app.MultiDoFTab);
            app.Label_30.Text = '自由度设置(1~5)';
            app.Spinner_5 = uispinner(app.MultiDoFTab);
            app.Spinner_5.ValueChangingFcn = createCallbackFcn(app, @Spinner_5ValueChanging, true);
            app.Spinner_5.Limits = [1 5];
            app.Spinner_5.RoundFractionalValues = 'on';
            app.Spinner_5.Value = 1;
            app.Image14 = uiimage(app.MultiDoFTab);
            app.Image14.ImageSource = fullfile(pathToMLAPP, 'multi-shadow1.png');
            app.Image15 = uiimage(app.MultiDoFTab);
            app.Image15.Visible = 'off';
            app.Image15.ImageSource = fullfile(pathToMLAPP, 'multi-shadow2.png');
            app.Image16 = uiimage(app.MultiDoFTab);
            app.Image16.Visible = 'off';
            app.Image16.ImageSource = fullfile(pathToMLAPP, 'multi-shadow3.png');
            app.Image17 = uiimage(app.MultiDoFTab);
            app.Image17.Visible = 'off';
            app.Image17.ImageSource = fullfile(pathToMLAPP, 'multi-shadow4.png');
            app.Image18 = uiimage(app.MultiDoFTab);
            app.Image18.ImageSource = fullfile(pathToMLAPP, 'multi-shadow5.png');
            app.Image18_2 = uiimage(app.MultiDoFTab);
            app.Image18_2.ImageSource = fullfile(pathToMLAPP, 'multi-shadow5.png');
            app.Image18_3 = uiimage(app.MultiDoFTab);
            app.Image18_3.ImageSource = fullfile(pathToMLAPP, 'multi-shadow5.png');
            app.Image18_4 = uiimage(app.MultiDoFTab);
            app.Image18_4.ImageSource = fullfile(pathToMLAPP, 'multi-shadow5.png');
            app.Label_31 = uilabel(app.MultiDoFTab);
            app.Label_31.Text = '自由振动对象';
            app.Spinner_6 = uispinner(app.MultiDoFTab);
            app.Spinner_6.Limits = [1 5];
            app.Spinner_6.Enable = 'off';
            app.Spinner_6.Value = 1;
            app.DropDown_6Label_2 = uilabel(app.MultiDoFTab);
            app.DropDown_6Label_2.Text = '显示数据';
            app.DropDown_6 = uidropdown(app.MultiDoFTab);
            app.DropDown_6.Items = {'位移', '速度', '加速度'};
            app.DropDown_6.Value = '位移';
            app.sEditField_2Label = uilabel(app.MultiDoFTab);
            app.sEditField_2Label.Text = '计算时间(s)';
            app.sEditField_2 = uieditfield(app.MultiDoFTab, 'numeric');
            app.sEditField_2.LowerLimitInclusive = 'off';
            app.sEditField_2.UpperLimitInclusive = 'off';
            app.sEditField_2.Limits = [0 Inf];
            app.sEditField_2.Value = 300;
            app.HzEditField_2Label = uilabel(app.MultiDoFTab);
            app.HzEditField_2Label.Text = '采样频率(Hz)';
            app.HzEditField_2 = uieditfield(app.MultiDoFTab, 'numeric');
            app.HzEditField_2.LowerLimitInclusive = 'off';
            app.HzEditField_2.UpperLimitInclusive = 'off';
            app.HzEditField_2.Limits = [0 Inf];
            app.HzEditField_2.Value = 10;
            app.CalculateButton_6 = uibutton(app.MultiDoFTab, 'push');
            app.CalculateButton_6.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_6Pushed, true);
            app.CalculateButton_6.Text = 'Calculate';
            app.ContinuumBodyTab = uitab(app.TabGroup);
            app.ContinuumBodyTab.Title = 'Continuum-Body';
            app.TabGroup2 = uitabgroup(app.ContinuumBodyTab);
            app.RodTab = uitab(app.TabGroup2);
            app.RodTab.Title = 'Rod';
            app.UIAxes2 = uiaxes(app.RodTab);
            title(app.UIAxes2, '杆纵向振动主振型')
            xlabel(app.UIAxes2, '杆长(m)')
            ylabel(app.UIAxes2, '纵向位移')
            app.Label_6 = uilabel(app.RodTab);
            app.Label_6.Text = '弹性杆纵向振动计算';
            app.Label_9 = uilabel(app.RodTab);
            app.Label_9.Text = {'1、计算等截面均质直杆纵向振动；'; '2、输入：杆长、杆截面积、弹性模量、密度；'; '3、输出：给定边界条件下的任意阶固有模态。'; '说明：坐标原点建立在左边界处，向右为正。'};
            app.Label_7 = uilabel(app.RodTab);
            app.Label_7.Text = '参数输入';
            app.LmEditFieldLabel = uilabel(app.RodTab);
            app.LmEditFieldLabel.Text = '杆长L(m)';
            app.LmEditField = uieditfield(app.RodTab, 'numeric');
            app.LmEditField.LowerLimitInclusive = 'off';
            app.LmEditField.Limits = [0 Inf];
            app.LmEditField.Value = 1;
            app.EGPaEditFieldLabel = uilabel(app.RodTab);
            app.EGPaEditFieldLabel.Text = '弹性模量E(GPa)';
            app.EGPaEditField = uieditfield(app.RodTab, 'numeric');
            app.EGPaEditField.LowerLimitInclusive = 'off';
            app.EGPaEditField.Limits = [0 Inf];
            app.EGPaEditField.Value = 70;
            app.Am2EditFieldLabel = uilabel(app.RodTab);
            app.Am2EditFieldLabel.Text = '横截面积A(m^2)';
            app.Am2EditField = uieditfield(app.RodTab, 'numeric');
            app.Am2EditField.LowerLimitInclusive = 'off';
            app.Am2EditField.Limits = [0 Inf];
            app.Am2EditField.Value = 0.01;
            app.kgm3EditFieldLabel = uilabel(app.RodTab);
            app.kgm3EditFieldLabel.Text = '密度ρ(kg/m^3)';
            app.kgm3EditField = uieditfield(app.RodTab, 'numeric');
            app.kgm3EditField.LowerLimitInclusive = 'off';
            app.kgm3EditField.Limits = [0 Inf];
            app.kgm3EditField.Value = 2700;
            app.Label_10 = uilabel(app.RodTab);
            app.Label_10.Text = '边界条件设置';
            app.Image4 = uiimage(app.RodTab);
            app.Image4.ImageSource = '杆-固定端-左.jpg';
            app.Label_11 = uilabel(app.RodTab);
            app.Label_11.Text = '左边界条件';
            app.DropDown = uidropdown(app.RodTab);
            app.DropDown.Items = {'固定端', '自由端', '弹性约束', '惯性约束'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '固定端';
            app.Label_12 = uilabel(app.RodTab);
            app.Label_12.Text = '右边界条件';
            app.DropDown_2 = uidropdown(app.RodTab);
            app.DropDown_2.Items = {'固定端', '自由端', '弹性约束', '惯性约束'};
            app.DropDown_2.Editable = 'on';
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = '固定端';
            app.CalculateButton_2 = uibutton(app.RodTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.Label_8 = uilabel(app.RodTab);
            app.Label_8.Text = '计算结果';
            app.radsEditField_7Label = uilabel(app.RodTab);
            app.radsEditField_7Label.Text = '固有圆频率(rad/s)';
            app.radsEditField_7 = uieditfield(app.RodTab, 'numeric');
            app.radsEditField_7.Editable = 'off';
            app.EditField5 = uieditfield(app.RodTab, 'numeric');
            app.EditField5.LowerLimitInclusive = 'off';
            app.EditField5.Limits = [0 Inf];
            app.EditField5.Visible = 'off';
            app.EditField5.Value = 1;
            app.Image4_2 = uiimage(app.RodTab);
            app.Image4_2.ImageSource = '杆-固定端-右.jpg';
            app.Label_14 = uilabel(app.RodTab);
            app.Label_14.Text = '求解阶数';
            app.Spinner = uispinner(app.RodTab);
            app.Spinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanging, true);
            app.Spinner.Limits = [1 Inf];
            app.Spinner.RoundFractionalValues = 'on';
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.EditField_4 = uieditfield(app.RodTab, 'numeric');
            app.EditField_4.LowerLimitInclusive = 'off';
            app.EditField_4.Limits = [0 Inf];
            app.EditField_4.Visible = 'off';
            app.EditField_4.Value = 1;
            app.NmLabel = uilabel(app.RodTab);
            app.NmLabel.Visible = 'off';
            app.NmLabel.Text = '输入弹簧刚度系数(N/m)';
            app.kgLabel = uilabel(app.RodTab);
            app.kgLabel.Visible = 'off';
            app.kgLabel.Text = '输入惯性载荷质量(kg)';
            app.kgLabel_2 = uilabel(app.RodTab);
            app.kgLabel_2.Visible = 'off';
            app.kgLabel_2.Text = '输入惯性载荷质量(kg)';
            app.NmLabel_2 = uilabel(app.RodTab);
            app.NmLabel_2.Visible = 'off';
            app.NmLabel_2.Text = '输入弹簧刚度系数(N/m)';
            app.Image11 = uiimage(app.RodTab);
            app.Image11.ImageSource = '中段.jpg';
            app.ShaftTab = uitab(app.TabGroup2);
            app.ShaftTab.Title = 'Shaft';
            app.UIAxes2_2 = uiaxes(app.ShaftTab);
            title(app.UIAxes2_2, '轴扭转振动主振型')
            xlabel(app.UIAxes2_2, '轴长(m)')
            ylabel(app.UIAxes2_2, '扭转角度')
            app.Label_15 = uilabel(app.ShaftTab);
            app.Label_15.Text = '弹性圆轴扭转振动计算';
            app.Label_16 = uilabel(app.ShaftTab);
            app.Label_16.Text = {'1、计算等截面均质圆轴扭转振动；'; '2、输入：轴长、截面半径、剪切模量、密度；'; '3、输出：给定边界条件下的任意阶固有模态。'; '说明：坐标原点建立在左边界处，向右为正。'};
            app.Label_17 = uilabel(app.ShaftTab);
            app.Label_17.Text = '参数输入';
            app.Label_18 = uilabel(app.ShaftTab);
            app.Label_18.Text = '边界条件设置';
            app.Image4_4 = uiimage(app.ShaftTab);
            app.Image4_4.ImageSource = '轴-固定端-左.jpg';
            app.CalculateButton_3 = uibutton(app.ShaftTab, 'push');
            app.CalculateButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3Pushed, true);
            app.CalculateButton_3.Text = 'Calculate';
            app.Label_19 = uilabel(app.ShaftTab);
            app.Label_19.Text = '计算结果';
            app.EditField5_2 = uieditfield(app.ShaftTab, 'numeric');
            app.EditField5_2.LowerLimitInclusive = 'off';
            app.EditField5_2.Limits = [0 Inf];
            app.EditField5_2.Visible = 'off';
            app.EditField5_2.Value = 1;
            app.Image4_5 = uiimage(app.ShaftTab);
            app.Image4_5.ImageSource = '轴-固定端-右.jpg';
            app.Image4_6 = uiimage(app.ShaftTab);
            app.Image4_6.ImageSource = '中段.jpg';
            app.EditField_5 = uieditfield(app.ShaftTab, 'numeric');
            app.EditField_5.LowerLimitInclusive = 'off';
            app.EditField_5.Limits = [0 Inf];
            app.EditField_5.Visible = 'off';
            app.EditField_5.Value = 1;
            app.LmLabel = uilabel(app.ShaftTab);
            app.LmLabel.Text = '轴长L(m)';
            app.LmEditField_2 = uieditfield(app.ShaftTab, 'numeric');
            app.LmEditField_2.LowerLimitInclusive = 'off';
            app.LmEditField_2.Limits = [0 Inf];
            app.LmEditField_2.Value = 1;
            app.GGPaEditFieldLabel = uilabel(app.ShaftTab);
            app.GGPaEditFieldLabel.Text = '剪切模量G(GPa)';
            app.GGPaEditField = uieditfield(app.ShaftTab, 'numeric');
            app.GGPaEditField.LowerLimitInclusive = 'off';
            app.GGPaEditField.Limits = [0 Inf];
            app.GGPaEditField.Value = 27;
            app.rmEditFieldLabel = uilabel(app.ShaftTab);
            app.rmEditFieldLabel.Text = '截面半径r(m)';
            app.rmEditField = uieditfield(app.ShaftTab, 'numeric');
            app.rmEditField.LowerLimitInclusive = 'off';
            app.rmEditField.Limits = [0 Inf];
            app.rmEditField.Value = 0.1;
            app.kgm3EditField_2Label = uilabel(app.ShaftTab);
            app.kgm3EditField_2Label.Text = '密度ρ(kg/m^3)';
            app.kgm3EditField_2 = uieditfield(app.ShaftTab, 'numeric');
            app.kgm3EditField_2.LowerLimitInclusive = 'off';
            app.kgm3EditField_2.Limits = [0 Inf];
            app.kgm3EditField_2.Value = 2700;
            app.DropDown_3Label = uilabel(app.ShaftTab);
            app.DropDown_3Label.Text = '左边界条件';
            app.DropDown_3 = uidropdown(app.ShaftTab);
            app.DropDown_3.Items = {'固定端', '自由端', '弹性约束', '惯性约束'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = '固定端';
            app.radsEditField_8Label = uilabel(app.ShaftTab);
            app.radsEditField_8Label.Text = '固有圆频率(rad/s)';
            app.radsEditField_8 = uieditfield(app.ShaftTab, 'numeric');
            app.radsEditField_8.Editable = 'off';
            app.Spinner_2Label = uilabel(app.ShaftTab);
            app.Spinner_2Label.Text = '求解阶数';
            app.Spinner_2 = uispinner(app.ShaftTab);
            app.Spinner_2.Limits = [1 Inf];
            app.Spinner_2.RoundFractionalValues = 'on';
            app.Spinner_2.Value = 1;
            app.DropDown_4Label = uilabel(app.ShaftTab);
            app.DropDown_4Label.Text = '右边界条件';
            app.DropDown_4 = uidropdown(app.ShaftTab);
            app.DropDown_4.Items = {'固定端', '自由端', '弹性约束', '惯性约束'};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.Value = '固定端';
            app.NmradLabel_2 = uilabel(app.ShaftTab);
            app.NmradLabel_2.Visible = 'off';
            app.NmradLabel_2.Text = '输入弹簧扭转刚度系数(N*m/rad)';
            app.kgm2Label_2 = uilabel(app.ShaftTab);
            app.kgm2Label_2.Visible = 'off';
            app.kgm2Label_2.Text = '输入惯性载荷转动惯量(kg*m^2)';
            app.kgm2Label = uilabel(app.ShaftTab);
            app.kgm2Label.Visible = 'off';
            app.kgm2Label.Text = '输入惯性载荷转动惯量(kg*m^2)';
            app.NmradLabel = uilabel(app.ShaftTab);
            app.NmradLabel.Visible = 'off';
            app.NmradLabel.Text = '输入弹簧扭转刚度系数(N*m/rad)';
            app.BeamTab = uitab(app.TabGroup2);
            app.BeamTab.Title = 'Beam';
            app.UIAxes2_3 = uiaxes(app.BeamTab);
            title(app.UIAxes2_3, '弯曲梁横向振动主振型')
            xlabel(app.UIAxes2_3, '梁长(m)')
            ylabel(app.UIAxes2_3, '弯曲挠度')
            app.Label_20 = uilabel(app.BeamTab);
            app.Label_20.Text = '弯曲梁横向振动计算';
            app.Label_21 = uilabel(app.BeamTab);
            app.Label_21.Text = {'1、计算均质等截面细直梁横向振动；'; '2、输入：长度、截面积、关于中性轴惯性矩、弹性模量、密度；'; '3、输出：给定边界条件下的任意阶固有模态。'; '说明：坐标原点建立在左边界处，向右为正。'};
            app.Label_22 = uilabel(app.BeamTab);
            app.Label_22.Text = '参数输入';
            app.Label_23 = uilabel(app.BeamTab);
            app.Label_23.Text = '边界条件设置';
            app.CalculateButton_4 = uibutton(app.BeamTab, 'push');
            app.CalculateButton_4.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_4Pushed, true);
            app.CalculateButton_4.Text = 'Calculate';
            app.Label_24 = uilabel(app.BeamTab);
            app.Label_24.Text = '计算结果';
            app.LmLabel_2 = uilabel(app.BeamTab);
            app.LmLabel_2.Text = '长度L(m)';
            app.LmEditField_3 = uieditfield(app.BeamTab, 'numeric');
            app.LmEditField_3.LowerLimitInclusive = 'off';
            app.LmEditField_3.Limits = [0 Inf];
            app.LmEditField_3.Value = 1;
            app.EGPaEditField_2Label = uilabel(app.BeamTab);
            app.EGPaEditField_2Label.Text = '弹性模量E(GPa)';
            app.EGPaEditField_2 = uieditfield(app.BeamTab, 'numeric');
            app.EGPaEditField_2.LowerLimitInclusive = 'off';
            app.EGPaEditField_2.Limits = [0 Inf];
            app.EGPaEditField_2.Value = 70;
            app.Im4EditFieldLabel = uilabel(app.BeamTab);
            app.Im4EditFieldLabel.Text = '惯性矩I(m^4)';
            app.Im4EditField = uieditfield(app.BeamTab, 'numeric');
            app.Im4EditField.LowerLimitInclusive = 'off';
            app.Im4EditField.Limits = [0 Inf];
            app.Im4EditField.Value = 0.0001;
            app.kgm3EditField_3Label = uilabel(app.BeamTab);
            app.kgm3EditField_3Label.Text = '密度ρ(kg/m^3)';
            app.kgm3EditField_3 = uieditfield(app.BeamTab, 'numeric');
            app.kgm3EditField_3.LowerLimitInclusive = 'off';
            app.kgm3EditField_3.Limits = [0 Inf];
            app.kgm3EditField_3.Value = 2700;
            app.DropDown_5Label = uilabel(app.BeamTab);
            app.DropDown_5Label.Text = '左边界条件';
            app.DropDown_5 = uidropdown(app.BeamTab);
            app.DropDown_5.Items = {'固定端', '自由端', '铰支端', '弹性约束', '惯性约束'};
            app.DropDown_5.Editable = 'on';
            app.DropDown_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_5ValueChanged, true);
            app.DropDown_5.Value = '固定端';
            app.radsEditField_9Label = uilabel(app.BeamTab);
            app.radsEditField_9Label.Text = '固有圆频率(rad/s)';
            app.radsEditField_9 = uieditfield(app.BeamTab, 'numeric');
            app.radsEditField_9.Editable = 'off';
            app.Spinner_3Label = uilabel(app.BeamTab);
            app.Spinner_3Label.Text = '求解阶数';
            app.Spinner_3 = uispinner(app.BeamTab);
            app.Spinner_3.Limits = [1 10];
            app.Spinner_3.RoundFractionalValues = 'on';
            app.Spinner_3.Value = 1;
            app.DropDown_6Label = uilabel(app.BeamTab);
            app.DropDown_6Label.Text = '右边界条件';
            app.CODE_NAME = uidropdown(app.BeamTab);
            app.CODE_NAME.Items = {'固定端', '自由端', '铰支端', '弹性约束', '惯性约束'};
            app.CODE_NAME.Editable = 'on';
            app.CODE_NAME.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.CODE_NAME.Value = '固定端';
            app.Am2EditField_2Label = uilabel(app.BeamTab);
            app.Am2EditField_2Label.Text = '截面积A(m^2)';
            app.Am2EditField_2 = uieditfield(app.BeamTab, 'numeric');
            app.Am2EditField_2.LowerLimitInclusive = 'off';
            app.Am2EditField_2.Limits = [0 Inf];
            app.Am2EditField_2.Value = 0.01;
            app.EditField_6 = uieditfield(app.BeamTab, 'numeric');
            app.EditField_6.LowerLimitInclusive = 'off';
            app.EditField_6.Limits = [0 Inf];
            app.EditField_6.Visible = 'off';
            app.EditField_6.Value = 10;
            app.NmLabel_3 = uilabel(app.BeamTab);
            app.NmLabel_3.Visible = 'off';
            app.NmLabel_3.Text = '弹簧刚度(N/m)';
            app.kgLabel_3 = uilabel(app.BeamTab);
            app.kgLabel_3.Visible = 'off';
            app.kgLabel_3.Text = '惯性载荷质量(kg)';
            app.EditField_7 = uieditfield(app.BeamTab, 'numeric');
            app.EditField_7.LowerLimitInclusive = 'off';
            app.EditField_7.Limits = [0 Inf];
            app.EditField_7.Visible = 'off';
            app.EditField_7.Value = 10;
            app.NmLabel_4 = uilabel(app.BeamTab);
            app.NmLabel_4.Visible = 'off';
            app.NmLabel_4.Text = '弹簧刚度(N/m)';
            app.kgLabel_4 = uilabel(app.BeamTab);
            app.kgLabel_4.Visible = 'off';
            app.kgLabel_4.Text = '惯性载荷质量(kg)';
            app.Image19 = uiimage(app.BeamTab);
            app.Image19.ImageSource = fullfile(pathToMLAPP, '梁-固定端-左.jpg');
            app.Image20 = uiimage(app.BeamTab);
            app.Image20.ImageSource = fullfile(pathToMLAPP, '梁-固定端-右.jpg');
            app.EditField_8 = uieditfield(app.BeamTab, 'numeric');
            app.EditField_8.LowerLimitInclusive = 'off';
            app.EditField_8.Limits = [0 Inf];
            app.EditField_8.Visible = 'off';
            app.EditField_8.Value = 10;
            app.EditField_9 = uieditfield(app.BeamTab, 'numeric');
            app.EditField_9.LowerLimitInclusive = 'off';
            app.EditField_9.Limits = [0 Inf];
            app.EditField_9.Visible = 'off';
            app.EditField_9.Value = 10;
            app.NmradLabel_3 = uilabel(app.BeamTab);
            app.NmradLabel_3.Visible = 'off';
            app.NmradLabel_3.Text = '扭簧刚度(N*m/rad)';
            app.NmradLabel_4 = uilabel(app.BeamTab);
            app.NmradLabel_4.Visible = 'off';
            app.NmradLabel_4.Text = '扭簧刚度(N*m/rad)';
            app.VibrationCalculatorLabel = uilabel(app.UIFigure);
            app.VibrationCalculatorLabel.Text = 'Vibration Calculator';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = '校徽.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end